classdef OxArpes_DataProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RemoveSpikesPanel        matlab.ui.container.Panel
        RemoveButton             matlab.ui.control.Button
        StrengthEditField        matlab.ui.control.NumericEditField
        StrengthEditFieldLabel   matlab.ui.control.Label
        DirectionButtonGroup     matlab.ui.container.ButtonGroup
        VerticalButton           matlab.ui.control.RadioButton
        HorizontalButton         matlab.ui.control.RadioButton
        SelfnormalisePanel       matlab.ui.container.Panel
        NormaliseButton          matlab.ui.control.Button
        NormalisetopeakCheckBox  matlab.ui.control.CheckBox
        RegionButtonGroup        matlab.ui.container.ButtonGroup
        ToEditField              matlab.ui.control.EditField
        ToEditFieldLabel         matlab.ui.control.Label
        FromEditField            matlab.ui.control.EditField
        FromEditFieldLabel       matlab.ui.control.Label
        IndexButton_2            matlab.ui.control.RadioButton
        RealscaleButton_2        matlab.ui.control.RadioButton
        NormalisationdirectionButtonGroup  matlab.ui.container.ButtonGroup
        XYButton                 matlab.ui.control.RadioButton
        XZButton                 matlab.ui.control.RadioButton
        YZButton                 matlab.ui.control.RadioButton
        ZButton                  matlab.ui.control.RadioButton
        YButton                  matlab.ui.control.RadioButton
        XButton                  matlab.ui.control.RadioButton
        FinestructurePanel       matlab.ui.container.Panel
        ZSmoothButton            matlab.ui.control.Button
        sigma_zEditField         matlab.ui.control.NumericEditField
        sigma_zLabel             matlab.ui.control.Label
        ndDerivativeButton       matlab.ui.control.Button
        SmoothButton             matlab.ui.control.Button
        sigma_yEditField         matlab.ui.control.NumericEditField
        sigma_yEditFieldLabel    matlab.ui.control.Label
        sigma_xEditField         matlab.ui.control.NumericEditField
        sigmaLabel               matlab.ui.control.Label
        TruncatedataPanel        matlab.ui.container.Panel
        ResetButton              matlab.ui.control.Button
        TruncateDataButton       matlab.ui.control.Button
        AxisscaleButtonGroup     matlab.ui.container.ButtonGroup
        IndexButton              matlab.ui.control.RadioButton
        RealscaleButton          matlab.ui.control.RadioButton
        EditField_6              matlab.ui.control.EditField
        EditField_5              matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField                matlab.ui.control.EditField
        ZLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        XLabel                   matlab.ui.control.Label
        ToLabel                  matlab.ui.control.Label
        FromLabel                matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            dataname = app.ListBox.Value;
            PlotSlices(dataname,'x');
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            data2 = data1.Gaussian_smoothen(app.sigma_xEditField.Value,app.sigma_yEditField.Value);
            assignin('base',[dataname '_sm'],data2);
        end

        % Button pushed function: ndDerivativeButton
        function ndDerivativeButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            [data2_x, data2_y] = data1.second_derivative(app.sigma_xEditField.Value,app.sigma_yEditField.Value);
            assignin('base',[dataname '_d2x'],data2_x);
            assignin('base',[dataname '_d2y'],data2_y);
        end

        % Button pushed function: TruncateDataButton
        function TruncateDataButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            
            xmin = app.EditField.Value;
            xmax = app.EditField_4.Value;
            ymin = app.EditField_2.Value;
            ymax = app.EditField_5.Value;
            zmin = app.EditField_3.Value;
            zmax = app.EditField_6.Value;

            if app.RealscaleButton.Value == 1
                if strcmp(xmin,'Min')
                    xmin = min(data1.x);
                else
                    xmin = str2num(xmin);
                end
                if strcmp(xmax,'Max')
                    xmax = max(data1.x);
                else
                    xmax = str2num(xmax);
                end
    
                if strcmp(ymin,'Min')
                    ymin = min(data1.y);
                else
                    ymin = str2num(ymin);
                end
                if strcmp(ymax,'Max')
                    ymax = max(data1.y);
                else
                    ymax = str2num(ymax);
                end


                if ndims(data1.value) == 2
                    data2 = data1.truncate(xmin,xmax,ymin,ymax);
                elseif ndims(data1.value) == 3
                    
                    if strcmp(zmin,'Min')
                        zmin = min(data1.z);
                    else
                        zmin = str2num(zmin);
                    end
                    if strcmp(zmax,'Max')
                        zmax = max(data1.z);
                    else
                        zmax = str2num(zmax);
                    end

                    data2 = data1.truncate(xmin,xmax,ymin,ymax,zmin,zmax);
                end

            elseif app.IndexButton.Value == 1
                if strcmp(xmin,'Min')
                    xmin = 1;
                else
                    xmin = str2num(xmin);
                end
                if strcmp(xmax,'Max')
                    xmax = length(data1.x);
                else
                    xmax = str2num(xmax);
                end
    
                if strcmp(ymin,'Min')
                    ymin = 1;
                else
                    ymin = str2num(ymin);
                end
                if strcmp(ymax,'Max')
                    ymax = length(data1.y);
                else
                    ymax = str2num(ymax);
                end
    
                if strcmp(zmin,'Min')
                    zmin = 1;
                else
                    zmin = str2num(zmin);
                end
                if strcmp(zmax,'Max')
                    zmax = length(data1.z);
                else
                    zmax = str2num(zmax);
                end

                if ndims(data1.value) == 2
                    data2 = data1.truncate_idx(xmin,xmax,ymin,ymax);
                elseif ndims(data1.value) == 3
                    data2 = data1.truncate_idx(xmin,xmax,ymin,ymax,zmin,zmax);
                end
            end

            assignin('base',[dataname '_tk'],data2);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField.Value = 'Min';
            app.EditField_2.Value = 'Min';
            app.EditField_3.Value = 'Min';
            app.EditField_4.Value = 'Max';
            app.EditField_5.Value = 'Max';
            app.EditField_6.Value = 'Max';
        end

        % Button pushed function: NormaliseButton
        function NormaliseButtonPushed(app, event)
            
            % get data
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);

            % get direction
            dir = app.NormalisationdirectionButtonGroup.SelectedObject.Text;

            % check dimension
            if ndims(data1.value) == 2

                switch dir
                    case 'X'

                        if strcmp(app.FromEditField.Value,'Min') && strcmp(app.ToEditField.Value,'Max')
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value,1);
                            else
                                sum_by_dir = max(data1.value,[],1);
                            end
                        else
                            if strcmp(app.FromEditField.Value,'Min')
                                n_ini = 1;
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_ini] = min(abs(data1.x-str2num(app.FromEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_ini = str2num(app.FromEditField.Value);
                                end
                            end

                            if strcmp(app.ToEditField.Value,'Max')
                                n_fin = length(data1.x);
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_fin] = min(abs(data1.x-str2num(app.ToEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_fin = str2num(app.ToEditField.Value);
                                end
                            end
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value(n_ini:n_fin,:),1);
                            else
                                sum_by_dir = max(data1.value(n_ini:n_fin,:),[],1);
                            end
                        end

%                         sum_by_dir = mean(data1.value,1);
                        div_mat = repmat(sum_by_dir,size(data1.value,1),1);
                        new_mat = data1.value ./ div_mat;


                    case 'Y'

                        if strcmp(app.FromEditField.Value,'Min') && strcmp(app.ToEditField.Value,'Max')
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value,2);
                            else
                                sum_by_dir = max(data1.value,[],2);
                            end
                        else
                            if strcmp(app.FromEditField.Value,'Min')
                                n_ini = 1;
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_ini] = min(abs(data1.y-str2num(app.FromEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_ini = str2num(app.FromEditField.Value);
                                end
                            end

                            if strcmp(app.ToEditField.Value,'Max')
                                n_fin = length(data1.y);
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_fin] = min(abs(data1.y-str2num(app.ToEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_fin = str2num(app.ToEditField.Value);
                                end
                            end

                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value(:,n_ini:n_fin),2);
                            else
                                sum_by_dir = max(data1.value(:,n_ini:n_fin),[],2);
                            end

                            
                        end

                        div_mat = repmat(sum_by_dir,1,size(data1.value,2));
                        new_mat = data1.value ./ div_mat;

                    otherwise
                        return
                end


            elseif ndims(data1.value) == 3
                switch dir
                    case 'X'

                        if strcmp(app.FromEditField.Value,'Min') && strcmp(app.ToEditField.Value,'Max')
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value,1);
                            else
                                sum_by_dir = max(data1.value,[],1);
                            end

                        else
                            if strcmp(app.FromEditField.Value,'Min')
                                n_ini = 1;
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_ini] = min(abs(data1.x-str2num(app.FromEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_ini = str2num(app.FromEditField.Value);
                                end
                            end

                            if strcmp(app.ToEditField.Value,'Max')
                                n_fin = length(data1.x);
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_fin] = min(abs(data1.x-str2num(app.ToEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_fin = str2num(app.ToEditField.Value);
                                end
                            end
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value(n_ini:n_fin,:,:),1);
                            else
                                sum_by_dir = max(data1.value(n_ini:n_fin,:,:),[],1);
                            end
                        end

%                         sum_by_dir = sum(data1.value,1);
                        div_mat = repmat(sum_by_dir,size(data1.value,1),1,1);
                        new_mat = data1.value ./ div_mat;

                    case 'Y'

                        if strcmp(app.FromEditField.Value,'Min') && strcmp(app.ToEditField.Value,'Max')
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value,2);
                            else
                                sum_by_dir = max(data1.value,[],2);
                            end
                            
                        else
                            if strcmp(app.FromEditField.Value,'Min')
                                n_ini = 1;
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_ini] = min(abs(data1.y-str2num(app.FromEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_ini = str2num(app.FromEditField.Value);
                                end
                            end

                            if strcmp(app.ToEditField.Value,'Max')
                                n_fin = length(data1.y);
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_fin] = min(abs(data1.y-str2num(app.ToEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_fin = str2num(app.ToEditField.Value);
                                end
                            end

                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value(:,n_ini:n_fin,:),2);
                            else
                                sum_by_dir = max(data1.value(:,n_ini:n_fin,:),[],2);
                            end
                        end

%                         sum_by_dir = sum(data1.value,2);
                        div_mat = repmat(sum_by_dir,1,size(data1.value,2),1);
                        new_mat = data1.value ./ div_mat;
                    case 'Z'

                        if strcmp(app.FromEditField.Value,'Min') && strcmp(app.ToEditField.Value,'Max')
                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value,3);
                            else
                                sum_by_dir = max(data1.value,[],3);
                            end
                            
                        else
                            if strcmp(app.FromEditField.Value,'Min')
                                n_ini = 1;
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_ini] = min(abs(data1.z-str2num(app.FromEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_ini = str2num(app.FromEditField.Value);
                                end
                            end

                            if strcmp(app.ToEditField.Value,'Max')
                                n_fin = length(data1.z);
                            else
                                if app.RealscaleButton_2.Value == 1
                                    [~,n_fin] = min(abs(data1.z-str2num(app.ToEditField.Value)));
                                elseif app.IndexButton_2.Value == 1
                                    n_fin = str2num(app.ToEditField.Value);
                                end
                            end

                            if app.NormalisetopeakCheckBox == 0
                                sum_by_dir = mean(data1.value(:,:,n_ini:n_fin),3);
                            else
                                sum_by_dir = max(data1.value(:,:,n_ini:n_fin),[],3);
                            end
                        end

%                         sum_by_dir = sum(data1.value,3);
                        div_mat = repmat(sum_by_dir,1,1,size(data1.value,3));
                        new_mat = data1.value ./ div_mat;
                    case 'YZ'
                        if app.NormalisetopeakCheckBox == 0
                            sum_by_dir = mean(data1.value,[2 3]);
                        else
                            sum_by_dir = max(data1.value,[],[2 3]);
                        end
                        div_mat = repmat(sum_by_dir,1,size(data1.value,2),size(data1.value,3));
                        new_mat = data1.value ./ div_mat;
                    case 'XZ'
                        if app.NormalisetopeakCheckBox == 0
                            sum_by_dir = mean(data1.value,[1 3]);
                        else
                            sum_by_dir = max(data1.value,[],[1 3]);
                        end
                        div_mat = repmat(sum_by_dir,size(data1.value,1),1,size(data1.value,3));
                        new_mat = data1.value ./ div_mat;
                    case 'XY'
                        if app.NormalisetopeakCheckBox == 0
                            sum_by_dir = mean(data1.value,[1 2]);
                        else
                            sum_by_dir = max(data1.value,[],[1 2]);
                        end
                        div_mat = repmat(sum_by_dir,size(data1.value,1),size(data1.value,2),1);
                        new_mat = data1.value ./ div_mat;
                end
            end


            % assign new data
            data2 = data1;
            data2.value = new_mat;
            assignin('base',[dataname '_' dir '_snor'],data2);


        end

        % Selection changed function: NormalisationdirectionButtonGroup
        function NormalisationdirectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalisationdirectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'YZ') || strcmp(selectedButton.Text,'XZ') || strcmp(selectedButton.Text,'XY')
                app.RegionButtonGroup.Enable = "off";
            else
                app.RegionButtonGroup.Enable = "on";

            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % get data
            dataname = app.ListBox.Value;
            DATA = evalin('base',dataname);
            ss = app.StrengthEditField.Value;

            switch ndims(DATA.value)
                case 2
                    if app.VerticalButton.Value == 1
                        DATA.value = medfilt1(DATA.value,ss,[],1);
                    else
                        DATA.value = medfilt1(DATA.value,ss,[],2);
                    end
                case 3
                    if app.VerticalButton.Value == 1
                        DATA.value = medfilt1(DATA.value,ss,[],2);
                    else
                        DATA.value = medfilt1(DATA.value,ss,[],3);
                    end
    %                 DATA.value = filloutliers(DATA.value,'linear','mean',3);
    %                 DATA.value(:,17,91) = zeros(size(DATA.value,1),1);
            end

            % assign new data
            assignin('base',[dataname '_rmspk'],DATA);
        end

        % Button pushed function: ZSmoothButton
        function ZSmoothButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            data2 = data1.Z_Gaussian_smoothen(app.sigma_zEditField.Value);
            assignin('base',[dataname '_zsm'],data2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TruncatedataPanel = uipanel(app.UIFigure);
            app.TruncatedataPanel.Title = 'Truncate data';
            app.FromLabel = uilabel(app.TruncatedataPanel);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.TruncatedataPanel);
            app.ToLabel.Text = 'To';
            app.XLabel = uilabel(app.TruncatedataPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.TruncatedataPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.TruncatedataPanel);
            app.ZLabel.Text = 'Z';
            app.EditField = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField.Value = 'Min';
            app.EditField_2 = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField_2.Value = 'Min';
            app.EditField_3 = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField_3.Value = 'Min';
            app.EditField_4 = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField_4.Value = 'Max';
            app.EditField_5 = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField_5.Value = 'Max';
            app.EditField_6 = uieditfield(app.TruncatedataPanel, 'text');
            app.EditField_6.Value = 'Max';
            app.AxisscaleButtonGroup = uibuttongroup(app.TruncatedataPanel);
            app.AxisscaleButtonGroup.Title = 'Axis scale';
            app.RealscaleButton = uiradiobutton(app.AxisscaleButtonGroup);
            app.RealscaleButton.Text = 'Real scale';
            app.RealscaleButton.Value = true;
            app.IndexButton = uiradiobutton(app.AxisscaleButtonGroup);
            app.IndexButton.Text = 'Index';
            app.TruncateDataButton = uibutton(app.TruncatedataPanel, 'push');
            app.TruncateDataButton.ButtonPushedFcn = createCallbackFcn(app, @TruncateDataButtonPushed, true);
            app.TruncateDataButton.Text = 'Truncate Data';
            app.ResetButton = uibutton(app.TruncatedataPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FinestructurePanel = uipanel(app.UIFigure);
            app.FinestructurePanel.Title = 'Fine structure';
            app.sigmaLabel = uilabel(app.FinestructurePanel);
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = '$\sigma_x$';
            app.sigma_xEditField = uieditfield(app.FinestructurePanel, 'numeric');
            app.sigma_xEditField.Value = 3;
            app.sigma_yEditFieldLabel = uilabel(app.FinestructurePanel);
            app.sigma_yEditFieldLabel.Interpreter = 'latex';
            app.sigma_yEditFieldLabel.Text = '$\sigma_y$';
            app.sigma_yEditField = uieditfield(app.FinestructurePanel, 'numeric');
            app.sigma_yEditField.Value = 3;
            app.SmoothButton = uibutton(app.FinestructurePanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.ndDerivativeButton = uibutton(app.FinestructurePanel, 'push');
            app.ndDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @ndDerivativeButtonPushed, true);
            app.ndDerivativeButton.Text = '2nd Derivative';
            app.sigma_zLabel = uilabel(app.FinestructurePanel);
            app.sigma_zLabel.Interpreter = 'tex';
            app.sigma_zLabel.Text = '\sigma_z';
            app.sigma_zEditField = uieditfield(app.FinestructurePanel, 'numeric');
            app.sigma_zEditField.Limits = [0 Inf];
            app.sigma_zEditField.Value = 5;
            app.ZSmoothButton = uibutton(app.FinestructurePanel, 'push');
            app.ZSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @ZSmoothButtonPushed, true);
            app.ZSmoothButton.Text = 'Z Smooth';
            app.SelfnormalisePanel = uipanel(app.UIFigure);
            app.SelfnormalisePanel.Title = 'Self normalise';
            app.NormalisationdirectionButtonGroup = uibuttongroup(app.SelfnormalisePanel);
            app.NormalisationdirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalisationdirectionButtonGroupSelectionChanged, true);
            app.NormalisationdirectionButtonGroup.Title = 'Normalisation direction';
            app.XButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.ZButton.Text = 'Z';
            app.YZButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.YZButton.Text = 'YZ';
            app.XZButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.XZButton.Text = 'XZ';
            app.XYButton = uiradiobutton(app.NormalisationdirectionButtonGroup);
            app.XYButton.Text = 'XY';
            app.RegionButtonGroup = uibuttongroup(app.SelfnormalisePanel);
            app.RegionButtonGroup.Title = 'Region';
            app.RealscaleButton_2 = uiradiobutton(app.RegionButtonGroup);
            app.RealscaleButton_2.Text = 'Real scale';
            app.RealscaleButton_2.Value = true;
            app.IndexButton_2 = uiradiobutton(app.RegionButtonGroup);
            app.IndexButton_2.Text = 'Index';
            app.FromEditFieldLabel = uilabel(app.RegionButtonGroup);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.RegionButtonGroup, 'text');
            app.FromEditField.Value = 'Min';
            app.ToEditFieldLabel = uilabel(app.RegionButtonGroup);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.RegionButtonGroup, 'text');
            app.ToEditField.Value = 'Max';
            app.NormalisetopeakCheckBox = uicheckbox(app.SelfnormalisePanel);
            app.NormalisetopeakCheckBox.Text = 'Normalise to peak';
            app.NormaliseButton = uibutton(app.SelfnormalisePanel, 'push');
            app.NormaliseButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseButtonPushed, true);
            app.NormaliseButton.Text = 'Normalise';
            app.RemoveSpikesPanel = uipanel(app.UIFigure);
            app.RemoveSpikesPanel.Title = 'Remove Spikes';
            app.DirectionButtonGroup = uibuttongroup(app.RemoveSpikesPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.HorizontalButton = uiradiobutton(app.DirectionButtonGroup);
            app.HorizontalButton.Text = 'Horizontal';
            app.VerticalButton = uiradiobutton(app.DirectionButtonGroup);
            app.VerticalButton.Text = 'Vertical';
            app.VerticalButton.Value = true;
            app.StrengthEditFieldLabel = uilabel(app.RemoveSpikesPanel);
            app.StrengthEditFieldLabel.Text = 'Strength';
            app.StrengthEditField = uieditfield(app.RemoveSpikesPanel, 'numeric');
            app.StrengthEditField.Limits = [0 Inf];
            app.StrengthEditField.Value = 5;
            app.RemoveButton = uibutton(app.RemoveSpikesPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end