classdef OxArpes_DataViewer_4D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARPES2DDataViewerUIFigure       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        PositionTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        E2EditField                     matlab.ui.control.NumericEditField
        E2EditFieldLabel                matlab.ui.control.Label
        E1EditField                     matlab.ui.control.NumericEditField
        E1EditFieldLabel                matlab.ui.control.Label
        K2EditField                     matlab.ui.control.NumericEditField
        K2EditFieldLabel                matlab.ui.control.Label
        K1EditField                     matlab.ui.control.NumericEditField
        K1EditFieldLabel                matlab.ui.control.Label
        AxisUnitCheckBox                matlab.ui.control.CheckBox
        EqualAxisCheckBox               matlab.ui.control.CheckBox
        ShowCrossHairCheckBox           matlab.ui.control.CheckBox
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ColourTab                       matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ColourRangeMomentumspaceSlider  matlab.ui.control.RangeSlider
        ColourRangeMomentumspaceSliderLabel  matlab.ui.control.Label
        ColourMapDropDown               matlab.ui.control.DropDown
        ColourMapDropDownLabel          matlab.ui.control.Label
        FlipCheckBox                    matlab.ui.control.CheckBox
        ColourRangeRealspaceSlider      matlab.ui.control.RangeSlider
        ColourRangeRealspaceSliderLabel  matlab.ui.control.Label
        OperationTab                    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ExtractCutButton                matlab.ui.control.Button
        CaptureImageButton              matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        WorkfuncEditField               matlab.ui.control.NumericEditField
        WorkfuncEditFieldLabel          matlab.ui.control.Label
        TempKEditField                  matlab.ui.control.NumericEditField
        TempKEditFieldLabel             matlab.ui.control.Label
        ModeEditField                   matlab.ui.control.EditField
        ModeEditFieldLabel              matlab.ui.control.Label
        BeamlineEditField               matlab.ui.control.EditField
        BeamlineEditFieldLabel          matlab.ui.control.Label
        PEEditField                     matlab.ui.control.NumericEditField
        PEEditFieldLabel                matlab.ui.control.Label
        PolarizationEditField           matlab.ui.control.EditField
        PolarizationEditFieldLabel      matlab.ui.control.Label
        hveVEditField                   matlab.ui.control.NumericEditField
        hveVEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        DATA_IN
        XX % Description
        YY
        K1
        K2
        E1
        E2
        DATA_CUT
        DATA_SPA

        FigXY
        FigKE
        FigXY_ch
        FigKE_rt
        
        DATA_VALUE_MAX
        C % colour order
    end
    
    methods (Access = private)
        
        function slice_data(app)
            % XY
            [~,cond1] = min(abs(app.DATA_IN.x - app.XX));
            [~,cond2] = min(abs(app.DATA_IN.y - app.YY));
            app.DATA_CUT = squeeze(app.DATA_IN.value(cond1,cond2,:,:));

            cond3 = (app.K1 <= app.DATA_IN.k) & (app.DATA_IN.k <= app.K2);
            cond4 = (app.E1 <= app.DATA_IN.z) & (app.DATA_IN.z <= app.E2);

            app.DATA_SPA = squeeze(sum(app.DATA_IN.value(:,:,cond3,cond4),[3 4]));

        end
        
        function display_data(app)

            app.FigXY = imagesc(app.UIAxes,app.DATA_IN.x,app.DATA_IN.y,app.DATA_SPA');
            set(app.UIAxes,'YDir','normal');

            app.FigKE = imagesc(app.UIAxes2,app.DATA_IN.k,app.DATA_IN.z,app.DATA_CUT');
            set(app.UIAxes2,'YDir','normal');

            value = app.AxisUnitCheckBox.Value;
            if value == 1
                app.UIAxes.XLabel.String = [app.DATA_IN.x_name ' (' app.DATA_IN.x_unit ')' ];
                app.UIAxes.YLabel.String = [app.DATA_IN.y_name ' (' app.DATA_IN.y_unit ')' ];
                app.UIAxes2.XLabel.String = [app.DATA_IN.k_name ' (' app.DATA_IN.k_unit ')' ];
                app.UIAxes2.YLabel.String = [app.DATA_IN.z_name ' (' app.DATA_IN.z_unit ')' ];
            else
                app.UIAxes.XLabel.String = [];
                app.UIAxes.YLabel.String = [];
                app.UIAxes2.XLabel.String = [];
                app.UIAxes2.YLabel.String = [];
            end


        end
        
        function update_positions(app,x,y,k1,e1,k2,e2)
            [~,cond1] = min(abs(x-app.DATA_IN.x));
            app.XX = app.DATA_IN.x(cond1);
            [~,cond2] = min(abs(y-app.DATA_IN.y));
            app.YY = app.DATA_IN.y(cond2);
            app.XEditField.Value = app.XX;
            app.YEditField.Value = app.YY;
            app.K1 = k1;
            app.K2 = k2;
            app.E1 = e1;
            app.E2 = e2;
            app.K1EditField.Value = k1;
            app.K2EditField.Value = k2;
            app.E1EditField.Value = e1;
            app.E2EditField.Value = e2;


            app.slice_data();
            app.update_images();

            app.FigXY_ch.Position = [app.XX app.YY];

        end
        
        function update_images(app)
            app.FigXY.CData = app.DATA_SPA';
            app.FigKE.CData = app.DATA_CUT';

        end
        
        function update_colourmap(app)

            cm = app.ColourMapDropDown.Value;
            fp = app.FlipCheckBox.Value;
            
            if strcmp(cm,'oxa_heat') 
                load("GUI\oxa_colourmap.mat","oxa_heat");
            elseif strcmp(cm,'oxa_blue')
                load("GUI\oxa_colourmap.mat","oxa_blue");
            elseif strcmp(cm,'oxa_redwhiteblue')
                load("GUI\oxa_colourmap.mat","oxa_redwhilteblue");
            end

            if fp
                cmf = flip(eval(cm));
                colormap(app.UIAxes,cmf);
                colormap(app.UIAxes2,cmf);
            else
                colormap(app.UIAxes,eval(cm));
                colormap(app.UIAxes2,eval(cm));
            end
            
            llmm = app.ColourRangeRealspaceSlider.Value ./100 .*double(app.DATA_VALUE_MAX*length(app.DATA_IN.k)*length(app.DATA_IN.z));
            clim(app.UIAxes,llmm);
            llmm2 = app.ColourRangeMomentumspaceSlider.Value ./100 .*double(app.DATA_VALUE_MAX);
            clim(app.UIAxes2,llmm2);


        end
        
        function update_info(app)
            if isfield(app.DATA_IN.info,'photon_energy')
                app.hveVEditField.Value = app.DATA_IN.info.photon_energy; 
            end
            if isfield(app.DATA_IN.info,'polarization')
                app.PolarizationEditField.Value = app.DATA_IN.info.polarization;
            end
            if isfield(app.DATA_IN.info,'aquisition_mode')
                app.ModeEditField.Value = app.DATA_IN.info.acquisition_mode;
            end
            if isfield(app.DATA_IN.info,'pass_energy')
                app.PEEditField.Value = app.DATA_IN.info.pass_energy;
            end
            if isfield(app.DATA_IN.info,'temperature')
                app.TempKEditField.Value = app.DATA_IN.info.temperature;
            end
            if isfield(app.DATA_IN.info,'beamline')
                app.BeamlineEditField.Value = app.DATA_IN.info.beamline;
            end
            if isfield(app.DATA_IN.info,'workfunction')
                app.WorkfuncEditField.Value = app.DATA_IN.info.workfunction;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DATA_INPUT)
            app.DATA_IN = DATA_INPUT;
            app.ARPES2DDataViewerUIFigure.Name = ['ARPES 4D Data Viewer - ' app.DATA_IN.name];
            app.C = orderedcolors("gem");

            % initialise
            app.XX = app.DATA_IN.x(round(length(app.DATA_IN.x)/2));
            app.YY = app.DATA_IN.y(round(length(app.DATA_IN.y)/2));
            app.XEditField.Value = app.XX;
            app.YEditField.Value = app.YY;

            app.K1 = min(app.DATA_IN.k);
            app.K2 = max(app.DATA_IN.k);
            app.E1 = min(app.DATA_IN.z);
            app.E2 = max(app.DATA_IN.z);
            app.K1EditField.Value = app.K1;
            app.K2EditField.Value = app.K2;
            app.E1EditField.Value = app.E1;
            app.E2EditField.Value = app.E2;

            app.DATA_VALUE_MAX = max(app.DATA_IN.value,[],'all');

            % slice data
            app.slice_data();

            % display data
            app.display_data();
            app.update_colourmap();

            % set crosshair
            app.FigXY_ch = drawcrosshair('Parent',app.UIAxes,'Position',[app.XX app.YY],'LineWidth',0.5,'Color',app.C(6,:),'Deletable',0);
            addlistener(app.FigXY_ch,'MovingROI',@(src,data) ...
                app.update_positions(data.CurrentPosition(1),data.CurrentPosition(2),app.K1,app.E1,app.K2,app.E2));

            app.FigKE_rt = drawrectangle('Parent',app.UIAxes2,'Position',[app.K1 app.E1 app.K2-app.K1 app.E2-app.E1],'LineWidth',0.5,'Color',app.C(7,:),'FaceAlpha',0,'Deletable',0);
            addlistener(app.FigKE_rt,'MovingROI',@(src,data) ...
                app.update_positions(app.XX,app.YY,data.CurrentPosition(1),data.CurrentPosition(2),...
                data.CurrentPosition(1)+data.CurrentPosition(3),data.CurrentPosition(2)+data.CurrentPosition(4)));

            % update info
            app.update_info();
        end

        % Value changed function: ColourMapDropDown, 
        % ...and 2 other components
        function ColourMapDropDownValueChanged(app, event)
            value = app.ColourMapDropDown.Value;
            app.update_colourmap();
        end

        % Value changed function: ShowCrossHairCheckBox
        function ShowCrossHairCheckBoxValueChanged(app, event)
            value = app.ShowCrossHairCheckBox.Value;
            if value == 1
                app.FigXY_ch.Visible = 'on';
            else
                app.FigXY_ch.Visible = 'off';
            end
        end

        % Value changed function: AxisUnitCheckBox
        function AxisUnitCheckBoxValueChanged(app, event)
            value = app.AxisUnitCheckBox.Value;
            if value == 1
                app.UIAxes.XLabel.String = [app.DATA_IN.x_name ' (' app.DATA_IN.x_unit ')' ];
                app.UIAxes.YLabel.String = [app.DATA_IN.y_name ' (' app.DATA_IN.y_unit ')' ];
                app.UIAxes2.XLabel.String = [app.DATA_IN.k_name ' (' app.DATA_IN.k_unit ')' ];
                app.UIAxes2.YLabel.String = [app.DATA_IN.z_name ' (' app.DATA_IN.z_unit ')' ];
            else
                app.UIAxes.XLabel.String = [];
                app.UIAxes.YLabel.String = [];
                app.UIAxes2.XLabel.String = [];
                app.UIAxes2.YLabel.String = [];
            end
        end

        % Value changed function: XEditField, YEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.update_positions(app.XEditField.Value,app.YEditField.Value);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            copygraphics(app.GridLayout2,'ContentType',"image",'Resolution',300);
        end

        % Value changed function: TempKEditField
        function TempKEditFieldValueChanged(app, event)
            value = app.TempKEditField.Value;
            app.DATA_IN.info.temperature = value;
        end

        % Value changed function: hveVEditField
        function hveVEditFieldValueChanged(app, event)
            value = app.hveVEditField.Value;
            app.DATA_IN.info.photon_energy = value;
        end

        % Button pushed function: ExtractCutButton
        function ExtractCutButtonPushed(app, event)
            CUT_OUT = OxA_CUT(app.DATA_IN.k,app.DATA_IN.z,app.DATA_CUT);
            CUT_OUT.info = app.DATA_IN.info;
            CUT_OUT.x_name = app.DATA_IN.k_name;
            CUT_OUT.x_unit = app.DATA_IN.k_unit;
            CUT_OUT.y_name = app.DATA_IN.z_name;
            CUT_OUT.y_unit = app.DATA_IN.z_unit;
            CUT_OUT.name = [app.DATA_IN.name '_X' erase(sprintf('%.2f',app.XX),{'.','-'}) '_Y' erase(sprintf('%.2f',app.YY),{'.','-'}) ];
            assignin("base",CUT_OUT.name,CUT_OUT);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARPES2DDataViewerUIFigure = uifigure('Visible', 'off');
            app.ARPES2DDataViewerUIFigure.Name = 'ARPES 2D Data Viewer';
            app.GridLayout = uigridlayout(app.ARPES2DDataViewerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout3 = uigridlayout(app.PositionTab);
            app.XEditFieldLabel = uilabel(app.GridLayout3);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.4f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.GridLayout3);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.4f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.ShowCrossHairCheckBox = uicheckbox(app.GridLayout3);
            app.ShowCrossHairCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCrossHairCheckBoxValueChanged, true);
            app.ShowCrossHairCheckBox.Text = 'Show Cross Hair';
            app.ShowCrossHairCheckBox.Value = true;
            app.EqualAxisCheckBox = uicheckbox(app.GridLayout3);
            app.EqualAxisCheckBox.Text = 'Equal Axis';
            app.AxisUnitCheckBox = uicheckbox(app.GridLayout3);
            app.AxisUnitCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxisUnitCheckBoxValueChanged, true);
            app.AxisUnitCheckBox.Text = 'Axis Unit';
            app.K1EditFieldLabel = uilabel(app.GridLayout3);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.K2EditFieldLabel = uilabel(app.GridLayout3);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.E1EditFieldLabel = uilabel(app.GridLayout3);
            app.E1EditFieldLabel.Text = 'E1';
            app.E1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.E2EditFieldLabel = uilabel(app.GridLayout3);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColourTab = uitab(app.TabGroup);
            app.ColourTab.Title = 'Colour';
            app.GridLayout4 = uigridlayout(app.ColourTab);
            app.ColourRangeRealspaceSliderLabel = uilabel(app.GridLayout4);
            app.ColourRangeRealspaceSliderLabel.Text = {'Colour Range'; 'Real-space'};
            app.ColourRangeRealspaceSlider = uislider(app.GridLayout4, 'range');
            app.ColourRangeRealspaceSlider.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.ColourRangeRealspaceSlider.Value = [0 50];
            app.FlipCheckBox = uicheckbox(app.GridLayout4);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.FlipCheckBox.Text = 'Flip';
            app.FlipCheckBox.Value = true;
            app.ColourMapDropDownLabel = uilabel(app.GridLayout4);
            app.ColourMapDropDownLabel.Text = 'Colour Map';
            app.ColourMapDropDown = uidropdown(app.GridLayout4);
            app.ColourMapDropDown.Items = {'gray', 'hot', 'cool', 'jet', 'parula', 'turbo', 'hsv', 'oxa_blue', 'oxa_heat', 'oxa_redwhiteblue'};
            app.ColourMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.ColourMapDropDown.Value = 'oxa_blue';
            app.ColourRangeMomentumspaceSliderLabel = uilabel(app.GridLayout4);
            app.ColourRangeMomentumspaceSliderLabel.Text = {'Colour Range'; 'Momentum-space'};
            app.ColourRangeMomentumspaceSlider = uislider(app.GridLayout4, 'range');
            app.ColourRangeMomentumspaceSlider.Limits = [0 150];
            app.ColourRangeMomentumspaceSlider.Value = [0 70];
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.GridLayout5 = uigridlayout(app.OperationTab);
            app.CaptureImageButton = uibutton(app.GridLayout5, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.ExtractCutButton = uibutton(app.GridLayout5, 'push');
            app.ExtractCutButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractCutButtonPushed, true);
            app.ExtractCutButton.Text = 'Extract Cut';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.GridLayout6 = uigridlayout(app.InfoTab);
            app.hveVEditFieldLabel = uilabel(app.GridLayout6);
            app.hveVEditFieldLabel.Text = 'hv (eV)';
            app.hveVEditField = uieditfield(app.GridLayout6, 'numeric');
            app.hveVEditField.ValueChangedFcn = createCallbackFcn(app, @hveVEditFieldValueChanged, true);
            app.PolarizationEditFieldLabel = uilabel(app.GridLayout6);
            app.PolarizationEditFieldLabel.Text = 'Polarization';
            app.PolarizationEditField = uieditfield(app.GridLayout6, 'text');
            app.PEEditFieldLabel = uilabel(app.GridLayout6);
            app.PEEditFieldLabel.Text = 'PE';
            app.PEEditField = uieditfield(app.GridLayout6, 'numeric');
            app.BeamlineEditFieldLabel = uilabel(app.GridLayout6);
            app.BeamlineEditFieldLabel.Text = 'Beamline';
            app.BeamlineEditField = uieditfield(app.GridLayout6, 'text');
            app.ModeEditFieldLabel = uilabel(app.GridLayout6);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.GridLayout6, 'text');
            app.TempKEditFieldLabel = uilabel(app.GridLayout6);
            app.TempKEditFieldLabel.Text = 'Temp. (K)';
            app.TempKEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TempKEditField.ValueChangedFcn = createCallbackFcn(app, @TempKEditFieldValueChanged, true);
            app.WorkfuncEditFieldLabel = uilabel(app.GridLayout6);
            app.WorkfuncEditFieldLabel.Text = 'Work func.';
            app.WorkfuncEditField = uieditfield(app.GridLayout6, 'numeric');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            colormap(app.UIAxes, 'gray')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            app.UIAxes2.YLimitMethod = 'tight';
            % Show the figure after all components are created
            app.ARPES2DDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataViewer_4D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARPES2DDataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARPES2DDataViewerUIFigure)
        end
    end
end