classdef OxArpes_FineStructure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        a_0Yf_maxSlider       matlab.ui.control.Slider
        a_0Yf_maxSliderLabel  matlab.ui.control.Label
        a_0Xf_maxSlider       matlab.ui.control.Slider
        a_0f_maxLabel         matlab.ui.control.Label
        CurvatureSwitch       matlab.ui.control.Switch
        CurvatureSwitchLabel  matlab.ui.control.Label
        YStepEditField        matlab.ui.control.NumericEditField
        YStepEditFieldLabel   matlab.ui.control.Label
        XStepEditField        matlab.ui.control.NumericEditField
        XStepEditFieldLabel   matlab.ui.control.Label
        yPixelSpinner         matlab.ui.control.Spinner
        yPixelSpinnerLabel    matlab.ui.control.Label
        xPixelSpinner         matlab.ui.control.Spinner
        xPixelSpinnerLabel    matlab.ui.control.Label
        XSlider               matlab.ui.control.Slider
        XSliderLabel          matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        SaveDataButtonGroup   matlab.ui.container.ButtonGroup
        SaveButton            matlab.ui.control.Button
        d2dx2CheckBox         matlab.ui.control.CheckBox
        d2dy2CheckBox         matlab.ui.control.CheckBox
        SmoothCheckBox        matlab.ui.control.CheckBox
        PlotButton            matlab.ui.control.Button
        RefreshButton         matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data_smooth % Description
        data_DX2
        data_DY2
        curvature_on
    end
    
    methods (Access = private)
        
        function results = showInAxis(app,ha1,data)
            % imagesc meshc
            cla(ha1);
            imagesc(ha1,data.x,data.y,data.value');
            xlabel(ha1,[data.x_name ' (' data.x_unit ')']);
            ylabel(ha1,[data.y_name ' (' data.y_unit ')']);

            set(ha1,'YDir','normal');
            colormap(ha1,flipud(gray));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Callback function: PlotButton, a_0Xf_maxSlider, a_0Yf_maxSlider, 
        % ...and 2 other components
        function PlotButtonPushed(app, event)
            dataname = app.ListBox.Value;
            
            data = evalin("base",dataname);
            
            if isa(data, 'OxArpes_2D_Data')
                app.showInAxis(app.UIAxes,data);

                app.data_smooth = data.Gaussian_smoothen(app.xPixelSpinner.Value, app.yPixelSpinner.Value);
                app.showInAxis(app.UIAxes2,app.data_smooth);
                
                if strcmp(app.CurvatureSwitch.Value,'Off')
                    [app.data_DX2,app.data_DY2] = data.second_derivative(app.xPixelSpinner.Value, app.yPixelSpinner.Value);
                else
                    [app.data_DX2,app.data_DY2] = data.curvature(app.xPixelSpinner.Value, app.yPixelSpinner.Value,app.a_0Xf_maxSlider.Value,app.a_0Yf_maxSlider.Value);
                end
                app.showInAxis(app.UIAxes3,app.data_DX2);
                app.showInAxis(app.UIAxes4,app.data_DY2);

            else

            end

        end

        % Value changed function: XStepEditField
        function XStepEditFieldValueChanged(app, event)
            value = app.XStepEditField.Value;
            app.xPixelSpinner.Step = value;
        end

        % Value changed function: YStepEditField
        function YStepEditFieldValueChanged(app, event)
            value = app.YStepEditField.Value;
            app.yPixelSpinner.Step = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.SmoothCheckBox.Value
                assignin('base',[app.ListBox.Value '_sm'],app.data_smooth);
            end
            if app.d2dx2CheckBox.Value
                assignin('base',[app.ListBox.Value '_dx2'],app.data_DX2);
            end
            if app.d2dy2CheckBox.Value
                assignin('base',[app.ListBox.Value '_dy2'],app.data_DY2);
            end
        end

        % Value changed function: CurvatureSwitch
        function CurvatureSwitchValueChanged(app, event)
            value = app.CurvatureSwitch.Value;
            if strcmp(value,'On')
                app.curvature_on = true;
                app.UIAxes3.Title.String = 'Curvature - X';
                app.UIAxes4.Title.String = 'Curvature - Y';
                app.PlotButtonPushed(event);
            elseif strcmp(value,'Off')
                app.curvature_on = false;
                app.UIAxes3.Title.String = '2nd Derivative - X';
                app.UIAxes4.Title.String = '2nd Derivative - Y';
                app.PlotButtonPushed(event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, '2nd Derivative - Y')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLimitMethod = 'tight';
            app.UIAxes4.YLimitMethod = 'tight';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '2nd Derivative - X')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Smooth')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout2);
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveDataButtonGroup = uibuttongroup(app.GridLayout2);
            app.SaveDataButtonGroup.Title = 'Save Data';
            app.SmoothCheckBox = uicheckbox(app.SaveDataButtonGroup);
            app.SmoothCheckBox.Text = 'Smooth';
            app.d2dy2CheckBox = uicheckbox(app.SaveDataButtonGroup);
            app.d2dy2CheckBox.Text = 'd2/dy2';
            app.d2dx2CheckBox = uicheckbox(app.SaveDataButtonGroup);
            app.d2dx2CheckBox.Text = 'd2/dx2';
            app.SaveButton = uibutton(app.SaveDataButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.XSliderLabel = uilabel(app.GridLayout3);
            app.XSliderLabel.Text = 'X Slider';
            app.XSlider = uislider(app.GridLayout3);
            app.xPixelSpinnerLabel = uilabel(app.GridLayout3);
            app.xPixelSpinnerLabel.Interpreter = 'tex';
            app.xPixelSpinnerLabel.Text = 'σ_x (Pixel)';
            app.xPixelSpinner = uispinner(app.GridLayout3);
            app.xPixelSpinner.Step = 0.5;
            app.xPixelSpinner.Limits = [0 Inf];
            app.xPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.xPixelSpinner.Value = 1;
            app.yPixelSpinnerLabel = uilabel(app.GridLayout3);
            app.yPixelSpinnerLabel.Interpreter = 'tex';
            app.yPixelSpinnerLabel.Text = 'σ_y (Pixel)';
            app.yPixelSpinner = uispinner(app.GridLayout3);
            app.yPixelSpinner.Step = 0.5;
            app.yPixelSpinner.Limits = [0 Inf];
            app.yPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.yPixelSpinner.Value = 1;
            app.XStepEditFieldLabel = uilabel(app.GridLayout3);
            app.XStepEditFieldLabel.Text = 'X Step';
            app.XStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XStepEditField.Limits = [0 Inf];
            app.XStepEditField.ValueChangedFcn = createCallbackFcn(app, @XStepEditFieldValueChanged, true);
            app.XStepEditField.Value = 0.5;
            app.YStepEditFieldLabel = uilabel(app.GridLayout3);
            app.YStepEditFieldLabel.Text = 'Y Step';
            app.YStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YStepEditField.Limits = [0 Inf];
            app.YStepEditField.ValueChangedFcn = createCallbackFcn(app, @YStepEditFieldValueChanged, true);
            app.YStepEditField.Value = 0.5;
            app.CurvatureSwitchLabel = uilabel(app.GridLayout3);
            app.CurvatureSwitchLabel.Text = 'Curvature';
            app.CurvatureSwitch = uiswitch(app.GridLayout3, 'slider');
            app.CurvatureSwitch.ValueChangedFcn = createCallbackFcn(app, @CurvatureSwitchValueChanged, true);
            app.a_0f_maxLabel = uilabel(app.GridLayout3);
            app.a_0f_maxLabel.Interpreter = 'tex';
            app.a_0f_maxLabel.Text = 'a_{0X} (~|f|_{max})';
            app.a_0Xf_maxSlider = uislider(app.GridLayout3);
            app.a_0Xf_maxSlider.Limits = [0 2];
            app.a_0Xf_maxSlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.a_0Xf_maxSlider.Value = 1;
            app.a_0Yf_maxSliderLabel = uilabel(app.GridLayout3);
            app.a_0Yf_maxSliderLabel.Interpreter = 'tex';
            app.a_0Yf_maxSliderLabel.Text = 'a_{0Y} (~|f|_{max})';
            app.a_0Yf_maxSlider = uislider(app.GridLayout3);
            app.a_0Yf_maxSlider.Limits = [0 2];
            app.a_0Yf_maxSlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.a_0Yf_maxSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_FineStructure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end