classdef OxArpes_DataList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataBrowserUIFigure    matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        LoadDataMenu           matlab.ui.container.Menu
        VisualiseMenu_2        matlab.ui.container.Menu
        PlotBrillouinZoneMenu  matlab.ui.container.Menu
        PlotCutinBZMenu        matlab.ui.container.Menu
        BZBSplotMenu           matlab.ui.container.Menu
        ProcessMenu            matlab.ui.container.Menu
        BasicprocessMenu       matlab.ui.container.Menu
        FDDfitMenu             matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        InfoTab                matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        TextArea               matlab.ui.control.TextArea
        PreviewTab             matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        OperationTab           matlab.ui.container.Tab
        Tab                    matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
        PlotButton             matlab.ui.control.Button
        RefreshButton          matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        ContextMenu            matlab.ui.container.ContextMenu
        QuickPlotMenu          matlab.ui.container.Menu
        PlotMenu               matlab.ui.container.Menu
        AlongXMenu             matlab.ui.container.Menu
        AlongYMenu             matlab.ui.container.Menu
        AlongZMenu             matlab.ui.container.Menu
        DOShorizontalMenu      matlab.ui.container.Menu
        DOSverticalMenu        matlab.ui.container.Menu
        DDataViewerMenu        matlab.ui.container.Menu
        EditVariableMenu       matlab.ui.container.Menu
        RenameMenu             matlab.ui.container.Menu
        DeleteMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        selected_data_name % Selected data list
    end
    
    methods (Access = private)
        
        function results = getDataTable(app)
            vars = evalin('base','who');
            types = cell(size(vars));
            for i = 1:length(vars)
                var = evalin('base',vars{i});
                types{i} = class(var);
            end
            results = table(vars,types,'VariableNames',["Name","Type"]);
        end
        
        function plot_selected_DOS(app,HorV)

            figure;
            hold on
        
            for i = 1:length(app.selected_data_name)

                data = evalin('base',app.selected_data_name{i});

                if ndims(data.value) == 2 && min(size(data.value)) ~= 1
                    x = data.y;
                    y = squeeze(sum(data.value,1))';
                    E_name = [data.y_name ' (' data.y_unit ')'];
                elseif ndims(data.value) == 3
                    x = data.z;
                    data.value(isnan(data.value)) = 0;
                    y = squeeze(sum(data.value,[1 2]))';
                    E_name = [data.z_name ' (' data.z_unit ')'];
                elseif ndims(data.value) == 2 && min(size(data.value)) == 1
                    x = data.x;
                    y = data.value;
                    E_name = '';
                end
        
        %         if length(dataName) > 1
                    y = y/mean(y,"all");
        %         end
        
                switch HorV
                    case 'H'
                        plot(x,y);
                        xlabel(E_name);
                        ylabel('Counts (a.u.)');
                    case 'V'
                        plot(y,x);
                        ylabel(E_name);
                        xlabel('Counts (a.u.)');
                end
            end
            
            hold off
        
            if length(app.selected_data_name)==1
                title(app.selected_data_name{1},'Interpreter','none');
            else
                legend(app.selected_data_name{:},'Interpreter','none');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RefreshButtonPushed;


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            datalist = app.getDataTable();
            app.UITable.ColumnName = datalist.Properties.VariableNames;
            app.UITable.Data = datalist.Variables;
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            loader_UI();
        end

        % Callback function: PlotButton, QuickPlotMenu, UITable
        function PlotButtonPushed(app, event)
            data = evalin('base',app.selected_data_name{1});
            try
                if ndims(data.value) == 3
                    PlotSlices(app.selected_data_name{1},'z');
                else
                    PlotSlices(app.selected_data_name{1},'x');
                end
            catch
            end

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.selected_data_name = app.UITable.Data(app.UITable.Selection,1);
            data = evalin('base',app.selected_data_name{1});
            
            %% update Info tab
            if ~isprop(data,'x_name')
                app.TextArea.Value = ['Name: ' app.selected_data_name{1} ', UNKNOWN TYPE'];
                return
            end

            refreshMessageBox()
            try
                if size(data.info.photon_energy,1)>1
                    addMessageToMessageBox(['Photon energy: ' num2str(round(min(data.info.photon_energy),2)) ' - ' num2str(round(max(data.info.photon_energy),2)) ' eV']);
                else
                    addMessageToMessageBox(['Photon energy: ' num2str(round(data.info.photon_energy,2)) ' eV']);
                end
                
                addMessageToMessageBox(convertStringsToChars(append('Polarisation: ', data.info.polarization)) );
                addMessageToMessageBox(convertStringsToChars(append('Acquisition mode: ', data.info.acquisition_mode)) );
                addMessageToMessageBox(['Pass Energy: ' num2str(data.info.pass_energy) ] );
                addMessageToMessageBox(['Temperature: ' num2str(round(data.info.temperature,2)) ' K'] );
            catch
            end

            addMessageToMessageBox('');
            addMessageToMessageBox(['X-axis: ' data.x_name ' (' data.x_unit ') ' num2str(data.x(1)) ' : ' num2str(data.x(2)-data.x(1)) ' : ' num2str(data.x(end))]);
            addMessageToMessageBox(['Y-axis: ' data.y_name ' (' data.y_unit ') ' num2str(data.y(1)) ' : ' num2str(data.y(2)-data.y(1)) ' : ' num2str(data.y(end))]);
            if ndims(data.value) == 3
                addMessageToMessageBox(['Z-axis: ' data.z_name ' (' data.z_unit ') ' num2str(data.z(1)) ' : ' num2str(data.z(2)-data.z(1)) ' : ' num2str(data.z(end))]);
            end

            %% update Preview tab
            if ndims(data.value) == 2
                % imagesc meshc
                imagesc(app.UIAxes,data.x,data.y,data.value');
                axis(app.UIAxes,'tight');
                xlabel(app.UIAxes,[data.x_name ' (' data.x_unit ')']);
                ylabel(app.UIAxes,[data.y_name ' (' data.y_unit ')']);
%                         title(app.UIAxes,data.name,'interpreter', 'none');
                app.UIAxes.Title.Visible=0;
                set(app.UIAxes,'YDir','normal');
                colormap(app.UIAxes,flipud(gray));
            elseif ndims(data.value) == 3
                if max(data.z)*min(data.z) < 0
                    data_slice = sum(data.value(:,:,abs(data.z)<0.2),3);
                else
                    data_slice = sum(data.value(:,:,abs(data.z - data.info.photon_energy + data.info.workfunction)<0.2),3);
                end

                imagesc(app.UIAxes,data.x,data.y,data_slice');
                axis(app.UIAxes,'tight');
                xlabel(app.UIAxes,[data.x_name ' (' data.x_unit ')']);
                ylabel(app.UIAxes,[data.y_name ' (' data.y_unit ')']);
%                         title(app.UIAxes,data.name,'interpreter', 'none');
                app.UIAxes.Title.Visible=0;
                set(app.UIAxes,'YDir','normal');
                colormap(app.UIAxes,flipud(gray));
            end


            function addMessageToMessageBox(msg)
                x = length(app.TextArea.Value);
                app.TextArea.Value{x+1} = msg;
            end

            function refreshMessageBox()
                app.TextArea.Value = ['Name: ' app.selected_data_name{1} ];
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loader_UI();
        end

        % Callback function
        function FinestructureMenuSelected(app, event)
            OxArpes_Visualise();
        end

        % Callback function
        function FDDfitMenuSelected(app, event)
            FDD_new();
        end

        % Callback function
        function BZBSplotMenuSelected(app, event)
            BZBS_plotter();
        end

        % Menu selected function: PlotBrillouinZoneMenu
        function PlotBrillouinZoneMenuSelected(app, event)
            plotBZ_V1();
        end

        % Menu selected function: PlotCutinBZMenu
        function PlotCutinBZMenuSelected(app, event)
            kz_plot();
        end

        % Callback function
        function TruncateMenuSelected(app, event)
            td_demo();
        end

        % Callback function
        function NormaliseMenuSelected(app, event)
            self_normalization_demo();
        end

        % Menu selected function: EditVariableMenu
        function EditVariableMenuSelected(app, event)
            openvar(app.selected_data_name{1});
        end

        % Menu selected function: AlongXMenu
        function AlongXMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'x');
        end

        % Menu selected function: AlongYMenu
        function AlongYMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'y');
        end

        % Menu selected function: AlongZMenu
        function AlongZMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'z');
        end

        % Menu selected function: DOShorizontalMenu
        function DOShorizontalMenuSelected(app, event)
            app.plot_selected_DOS('H');
        end

        % Menu selected function: DOSverticalMenu
        function DOSverticalMenuSelected(app, event)
            app.plot_selected_DOS('V');
        end

        % Menu selected function: DDataViewerMenu
        function DDataViewerMenuSelected(app, event)
            data = evalin('base',app.selected_data_name{1});
            OxArpes_DataViewer(data);
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            prompt = {'Please enter the new name'};
            dlgtitle = 'Rename a variable';
            fieldsize = [1 40];
            definput = {app.selected_data_name{1}};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            
            old_var = evalin('base',app.selected_data_name{1});
            assignin('base',answer{1},old_var);
            evalin("base",['clear ' app.selected_data_name{1}]);
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            answer = questdlg(sprintf(['Would you like to delete all ' num2str(length(app.selected_data_name)) ' variable(s)?\n', app.selected_data_name{1} '\n...']), ...
                'Yes', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    for i=1:length(app.selected_data_name)
                        evalin("base",['clear ' app.selected_data_name{i}]);
                    end
                case 'No'
                    return
            end

        end

        % Menu selected function: BZBSplotMenu
        function BZBSplotMenuSelected2(app, event)
            BZBS_plotter();
        end

        % Menu selected function: FDDfitMenu
        function FDDfitMenuSelected2(app, event)
            FDD_new();
        end

        % Menu selected function: BasicprocessMenu
        function BasicprocessMenuSelected2(app, event)
            OxArpes_DataProcess();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataBrowserUIFigure = uifigure('Visible', 'off');
            app.DataBrowserUIFigure.Name = 'Data Browser';
            app.Menu = uimenu(app.DataBrowserUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadDataMenu = uimenu(app.Menu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.VisualiseMenu_2 = uimenu(app.DataBrowserUIFigure);
            app.VisualiseMenu_2.Text = 'Visualise';
            app.PlotBrillouinZoneMenu = uimenu(app.VisualiseMenu_2);
            app.PlotBrillouinZoneMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotBrillouinZoneMenuSelected, true);
            app.PlotBrillouinZoneMenu.Text = 'Plot Brillouin Zone';
            app.PlotCutinBZMenu = uimenu(app.VisualiseMenu_2);
            app.PlotCutinBZMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotCutinBZMenuSelected, true);
            app.PlotCutinBZMenu.Text = 'Plot Cut in BZ';
            app.BZBSplotMenu = uimenu(app.VisualiseMenu_2);
            app.BZBSplotMenu.MenuSelectedFcn = createCallbackFcn(app, @BZBSplotMenuSelected2, true);
            app.BZBSplotMenu.Separator = 'on';
            app.BZBSplotMenu.Text = 'BZBS plot';
            app.ProcessMenu = uimenu(app.DataBrowserUIFigure);
            app.ProcessMenu.Text = 'Process';
            app.BasicprocessMenu = uimenu(app.ProcessMenu);
            app.BasicprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @BasicprocessMenuSelected2, true);
            app.BasicprocessMenu.Separator = 'on';
            app.BasicprocessMenu.Text = 'Basic process';
            app.FDDfitMenu = uimenu(app.ProcessMenu);
            app.FDDfitMenu.MenuSelectedFcn = createCallbackFcn(app, @FDDfitMenuSelected2, true);
            app.FDDfitMenu.Text = 'FDD fit';
            app.GridLayout = uigridlayout(app.DataBrowserUIFigure);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.GridLayout2 = uigridlayout(app.InfoTab);
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Editable = 'off';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewTab);
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ContextMenu = uicontextmenu(app.DataBrowserUIFigure);
            app.QuickPlotMenu = uimenu(app.ContextMenu);
            app.QuickPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.QuickPlotMenu.Text = 'Quick Plot';
            app.PlotMenu = uimenu(app.ContextMenu);
            app.PlotMenu.Text = 'Plot';
            app.AlongXMenu = uimenu(app.PlotMenu);
            app.AlongXMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongXMenuSelected, true);
            app.AlongXMenu.Text = 'Along X';
            app.AlongYMenu = uimenu(app.PlotMenu);
            app.AlongYMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongYMenuSelected, true);
            app.AlongYMenu.Text = 'Along Y';
            app.AlongZMenu = uimenu(app.PlotMenu);
            app.AlongZMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongZMenuSelected, true);
            app.AlongZMenu.Text = 'Along Z';
            app.DOShorizontalMenu = uimenu(app.PlotMenu);
            app.DOShorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @DOShorizontalMenuSelected, true);
            app.DOShorizontalMenu.Separator = 'on';
            app.DOShorizontalMenu.Text = 'DOS, horizontal';
            app.DOSverticalMenu = uimenu(app.PlotMenu);
            app.DOSverticalMenu.MenuSelectedFcn = createCallbackFcn(app, @DOSverticalMenuSelected, true);
            app.DOSverticalMenu.Text = 'DOS, vertical';
            app.DDataViewerMenu = uimenu(app.PlotMenu);
            app.DDataViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @DDataViewerMenuSelected, true);
            app.DDataViewerMenu.Separator = 'on';
            app.DDataViewerMenu.Text = '3D Data Viewer';
            app.EditVariableMenu = uimenu(app.ContextMenu);
            app.EditVariableMenu.MenuSelectedFcn = createCallbackFcn(app, @EditVariableMenuSelected, true);
            app.EditVariableMenu.Separator = 'on';
            app.EditVariableMenu.Text = 'Edit Variable';
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Separator = 'on';
            app.RenameMenu.Text = 'Rename';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.DataBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataList

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataBrowserUIFigure)
        end
    end
end