classdef OxArpes_DataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPanelUIFigure   matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        PositionTab            matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        GridLayout8            matlab.ui.container.GridLayout
        ZLabel                 matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        GridLayout7            matlab.ui.container.GridLayout
        YLabel                 matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        GridLayout6            matlab.ui.container.GridLayout
        XLabel                 matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        GridLayout5            matlab.ui.container.GridLayout
        ZLabel_2               matlab.ui.control.Label
        ZWidthEditField        matlab.ui.control.NumericEditField
        GridLayout4            matlab.ui.container.GridLayout
        YLabel_2               matlab.ui.control.Label
        YWidthEditField        matlab.ui.control.NumericEditField
        GridLayout3            matlab.ui.container.GridLayout
        XLabel_2               matlab.ui.control.Label
        XWidthEditField        matlab.ui.control.NumericEditField
        ZSlider                matlab.ui.control.Slider
        YSlider                matlab.ui.control.Slider
        XSlider                matlab.ui.control.Slider
        ViewOptionButtonGroup  matlab.ui.container.ButtonGroup
        CrosshairCheckBox      matlab.ui.control.CheckBox
        ALLButton              matlab.ui.control.RadioButton
        ZButton                matlab.ui.control.RadioButton
        YButton                matlab.ui.control.RadioButton
        XButton                matlab.ui.control.RadioButton
        ColourTab              matlab.ui.container.Tab
        GridLayout9            matlab.ui.container.GridLayout
        GridLayout13           matlab.ui.container.GridLayout
        ColourMapLabel         matlab.ui.control.Label
        FlipCheckBox           matlab.ui.control.CheckBox
        ColorMapDropDown       matlab.ui.control.DropDown
        GridLayout12           matlab.ui.container.GridLayout
        GammaLabel             matlab.ui.control.Label
        GammaEditField         matlab.ui.control.NumericEditField
        GridLayout11           matlab.ui.container.GridLayout
        MaxLabel               matlab.ui.control.Label
        MaxEditField           matlab.ui.control.NumericEditField
        GridLayout10           matlab.ui.container.GridLayout
        MinLabel               matlab.ui.control.Label
        MinEditField           matlab.ui.control.NumericEditField
        GammaSlider            matlab.ui.control.Slider
        MaxSlider              matlab.ui.control.Slider
        MinSlider              matlab.ui.control.Slider
        HistogramTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        % value properties
        MAP; % Description

        xmin;
        xmax;
        ymin;
        ymax;
        zmin;
        zmax;

        dmax;
        dmin;

        % postions
        XX;
        YY;
        ZZ;

        % object properties
        FigX;
        FigY;
        FigZ;
        FigX_axe;
        FigY_axe;
        FigZ_axe;
        FigX_imag;
        FigY_imag;
        FigZ_imag;

%         FigS;
%         FigS_axe;
%         FigS_imag;
        
        FigX_crosshair;
        FigY_crosshair;
        FigZ_crosshair;
    end
    
    methods (Access = private)
        
        function plot_figures(app)
            %% plot Fig x
            cond = ( abs(app.MAP.x - app.XX) <= app.XWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.x - app.XX));
            end
            
            imag_x = squeeze(mean(app.MAP.value(cond,:,:),1))';
            app.FigX_imag = imagesc(app.FigX_axe,app.MAP.y,app.MAP.z,imag_x);

            xlabel(app.FigX_axe,[app.MAP.y_name ' (' app.MAP.y_unit ')']);
            ylabel(app.FigX_axe,[app.MAP.z_name ' (' app.MAP.z_unit ')']);
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigX_axe,'YDir','normal');
            % colormap(app.FigX_axe,flipud(gray));
%             caxis(app.FigX_axe,[app.dmin,app.dmax]);


            %% plot Fig y
            cond = ( abs(app.MAP.y - app.YY) <= app.YWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.y - app.YY));
            end

            imag_y = squeeze(mean(app.MAP.value(:,cond,:),2))';
            app.FigY_imag = imagesc(app.FigY_axe,app.MAP.x,app.MAP.z,imag_y);
            xlabel(app.FigY_axe,[app.MAP.x_name ' (' app.MAP.x_unit ')']);
            ylabel(app.FigY_axe,[app.MAP.z_name ' (' app.MAP.z_unit ')']);
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigY_axe,'YDir','normal');
            % colormap(app.FigY_axe,flipud(gray));
%             caxis(app.FigY_axe,[app.dmin,app.dmax]);

            %% plot Fig z
            cond = ( abs(app.MAP.z - app.ZZ) <= app.ZWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.z - app.ZZ));
            end

            imag_z = squeeze(mean(app.MAP.value(:,:,cond),3))';
            app.FigZ_imag = imagesc(app.FigZ_axe,app.MAP.x,app.MAP.y,imag_z);

            xlabel(app.FigZ_axe,[app.MAP.x_name ' (' app.MAP.x_unit ')']);
            ylabel(app.FigZ_axe,[app.MAP.y_name ' (' app.MAP.y_unit ')']);

            if strcmp(app.MAP.x_unit,app.MAP.y_unit)
                set(app.FigZ_axe,'DataAspectRatio',[1 1 1]);
            end
%             title(axes,app.MAP.name,'interpreter', 'none');
            set(app.FigZ_axe,'YDir','normal');
            % colormap(app.FigZ_axe,flipud(gray));
%             caxis(app.FigZ_axe,[app.dmin,app.dmax]);


%             %% plot slice
%             app.FigS_imag = slice(app.FigS_axe,app.MAP.y,app.MAP.x,app.MAP.z,app.MAP.value,app.YY,app.XX,app.ZZ,'nearest');
%             colormap(app.FigS_axe,flipud(gray));
%             shading(app.FigS_axe,'flat');
            app.updateColourmap();
            
            %% crosshair
            app.FigX_crosshair = drawcrosshair('Parent',app.FigX_axe,'Position',[app.YY app.ZZ],'LineWidth',1,'Color','b');
            app.FigY_crosshair = drawcrosshair('Parent',app.FigY_axe,'Position',[app.XX app.ZZ],'LineWidth',1,'Color','b');
            app.FigZ_crosshair = drawcrosshair('Parent',app.FigZ_axe,'Position',[app.XX app.YY],'LineWidth',1,'Color','b');
            addlistener(app.FigX_crosshair,'MovingROI',@(src,data) app.update_positions(app.XX,data.CurrentPosition(1),data.CurrentPosition(2)));
            addlistener(app.FigY_crosshair,'MovingROI',@(src,data) app.update_positions(data.CurrentPosition(1),app.YY,data.CurrentPosition(2)));
            addlistener(app.FigZ_crosshair,'MovingROI',@(src,data) app.update_positions(data.CurrentPosition(1),data.CurrentPosition(2),app.ZZ));

        end

        function update_images(app)
            % update figure x
            cond = ( abs(app.MAP.x - app.XX) <= app.XWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.x - app.XX));
            end
            imag = squeeze(mean(app.MAP.value(cond,:,:),1))';
            app.FigX_imag.CData = imag;

            % update figure y
            cond = ( abs(app.MAP.y - app.YY) <= app.YWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.y - app.YY));
            end
            imag = squeeze(mean(app.MAP.value(:,cond,:),2))';
            app.FigY_imag.CData = imag;

            % update figure z
            cond = ( abs(app.MAP.z - app.ZZ) <= app.ZWidthEditField.Value/2 );
            if ~any(cond)
                [~,cond] = min(abs(app.MAP.z - app.ZZ));
            end
            imag = squeeze(mean(app.MAP.value(:,:,cond),3))';
            app.FigZ_imag.CData = imag;

%             cla(app.FigS_axe);
%             app.FigS_imag = slice(app.FigS_axe,app.MAP.y,app.MAP.x,app.MAP.z,app.MAP.value,app.XX,app.YY,app.ZZ,'nearest');
%             colormap(app.FigS_axe,flipud(gray));
%             shading(app.FigS_axe,'flat');

        end

        function update_positions(app,x,y,z)
            app.XX = x;
            app.YY = y;
            app.ZZ = z;
            app.update_images();

            app.FigX_crosshair.Position = [y z];
            app.FigY_crosshair.Position = [x z];
            app.FigZ_crosshair.Position = [x y];
            app.XSlider.Value = x;
            app.YSlider.Value = y;
            app.ZSlider.Value = z;
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = z;

        end

        
        function updateColourmap(app)
            cm = app.ColorMapDropDown.Value;
            fp = app.FlipCheckBox.Value;
            if fp
                cmf = flip(eval(cm));
                colormap(app.FigX_axe,cmf);
                colormap(app.FigY_axe,cmf);
                colormap(app.FigZ_axe,cmf);
            else
                colormap(app.FigX_axe,cm);
                colormap(app.FigY_axe,cm);
                colormap(app.FigZ_axe,cm);
            end
            
            ll = max(app.MAP.value,[],'all') * app.MinEditField.Value /100;
            mm = max(app.MAP.value,[],'all') * app.MaxEditField.Value /100;

            clim(app.FigX_axe,[ll mm]);
            clim(app.FigY_axe,[ll mm]);
            clim(app.FigZ_axe,[ll mm]);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MAP_data)
            app.MAP = MAP_data;
         
            app.xmin = min(app.MAP.x);
            app.xmax = max(app.MAP.x);
            app.ymin = min(app.MAP.y);
            app.ymax = max(app.MAP.y);
            app.zmin = min(app.MAP.z);
            app.zmax = max(app.MAP.z);

            app.ControlPanelUIFigure.Name = ['Viewer - ' app.MAP.name];


            % set XYZ limits
            app.XSlider.Limits = [app.xmin app.xmax];
            app.YSlider.Limits = [app.ymin app.ymax];
            app.ZSlider.Limits = [app.zmin app.zmax];
            app.XEditField.Limits = [app.xmin app.xmax];
            app.YEditField.Limits = [app.ymin app.ymax];
            app.ZEditField.Limits = [app.zmin app.zmax];

            % set XYZ values
            app.XX = (app.xmin+app.xmax)/2;
            app.YY = (app.ymin+app.ymax)/2;
            app.ZZ = (app.zmin+app.zmax)/2;

            app.XSlider.Value = app.XX;
            app.YSlider.Value = app.YY;
            app.ZSlider.Value = app.ZZ;
            app.XEditField.Value = app.XX;
            app.YEditField.Value = app.YY;
            app.ZEditField.Value = app.ZZ;


%             app.dmin = min(app.MAP.value,[],'all');
%             app.dmax = max(app.MAP.value,[],'all');

            % set up the figures
            app.FigX = figure('Name',['Data plot - ' app.MAP.name ' - X']);
            app.FigY = figure('Name',['Data plot - ' app.MAP.name ' - Y']);
            app.FigZ = figure('Name',['Data plot - ' app.MAP.name ' - Z']); 
            app.FigX_axe = axes('parent',app.FigX);
            app.FigY_axe = axes('parent',app.FigY);
            app.FigZ_axe = axes('parent',app.FigZ);

%             app.FigS = figure('Name',['Data plot - ' app.MAP.name ' - Slice']); 
%             app.FigS_axe = axes('parent',app.FigS);

            % update three figures
            app.plot_figures();

        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            option = selectedButton.Text;
            if strcmp(option,'X')
                app.XSlider.Enable = "on";
                app.YSlider.Enable = "off";
                app.ZSlider.Enable = "off";
                app.XEditField.Enable = "on";
                app.YEditField.Enable = "off";
                app.ZEditField.Enable = "off";

                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';
                app.FigX_crosshair.Visible = 'off';
                app.FigY_crosshair.Visible = 'off';
                app.FigZ_crosshair.Visible = 'off';

                app.FigX.Visible = 'on';
                app.FigY.Visible = 'off';
                app.FigZ.Visible = 'off';

            elseif strcmp(option,'Y')
                app.XSlider.Enable = "off";
                app.YSlider.Enable = "on";
                app.ZSlider.Enable = "off";
                app.XEditField.Enable = "off";
                app.YEditField.Enable = "on";
                app.ZEditField.Enable = "off";

                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';
                app.FigX_crosshair.Visible = 'off';
                app.FigY_crosshair.Visible = 'off';
                app.FigZ_crosshair.Visible = 'off';

                app.FigX.Visible = 'off';
                app.FigY.Visible = 'on';
                app.FigZ.Visible = 'off';

            elseif strcmp(option,'Z')
                app.XSlider.Enable = "off";
                app.YSlider.Enable = "off";
                app.ZSlider.Enable = "on";
                app.XEditField.Enable = "off";
                app.YEditField.Enable = "off";
                app.ZEditField.Enable = "on";

                app.CrosshairCheckBox.Value = 0;
                app.CrosshairCheckBox.Enable = 'off';
                app.FigX_crosshair.Visible = 'off';
                app.FigY_crosshair.Visible = 'off';
                app.FigZ_crosshair.Visible = 'off';

                app.FigX.Visible = 'off';
                app.FigY.Visible = 'off';
                app.FigZ.Visible = 'on';

            elseif strcmp(option,'ALL')
                app.XSlider.Enable = "on";
                app.YSlider.Enable = "on";
                app.ZSlider.Enable = "on";
                app.XEditField.Enable = "on";
                app.YEditField.Enable = "on";
                app.ZEditField.Enable = "on";

                app.CrosshairCheckBox.Value = 1;
                app.CrosshairCheckBox.Enable = 'on';
                app.FigX_crosshair.Visible = 'on';
                app.FigY_crosshair.Visible = 'on';
                app.FigZ_crosshair.Visible = 'on';


                app.FigX.Visible = 'on';
                app.FigY.Visible = 'on';
                app.FigZ.Visible = 'on';
            end
        end

        % Value changed function: CrosshairCheckBox
        function CrosshairCheckBoxValueChanged(app, event)
            if app.CrosshairCheckBox.Value == 1
                app.FigX_crosshair.Visible = 'on';
                app.FigY_crosshair.Visible = 'on';
                app.FigZ_crosshair.Visible = 'on';
            else
                app.FigX_crosshair.Visible = 'off';
                app.FigY_crosshair.Visible = 'off';
                app.FigZ_crosshair.Visible = 'off'; 
            end
        end

        % Close request function: ControlPanelUIFigure
        function ControlPanelUIFigureCloseRequest(app, event)
            try
                close(app.FigX);
                close(app.FigY);
                close(app.FigZ);
%                 close(app.FigS);
            catch
            end
            delete(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.update_positions(event.Value,app.YY,app.ZZ);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.update_positions(app.XX,event.Value,app.ZZ);
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.update_positions(app.XX,app.YY,event.Value);
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            app.update_positions(app.XX,app.YY,event.Value);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            app.update_positions(app.XX,event.Value,app.ZZ);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            app.update_positions(event.Value,app.YY,app.ZZ);
        end

        % Value changed function: XWidthEditField
        function XWidthEditFieldValueChanged(app, event)
            app.update_images();
        end

        % Value changed function: YWidthEditField
        function YWidthEditFieldValueChanged(app, event)
            app.update_images();
        end

        % Value changed function: ZWidthEditField
        function ZWidthEditFieldValueChanged(app, event)
            app.update_images();
        end

        % Callback function
        function KConvertButtonPushed(app, event)

        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            value = app.ColorMapDropDown.Value;
            app.updateColourmap();
        end

        % Value changed function: FlipCheckBox
        function FlipCheckBoxValueChanged(app, event)
            value = app.FlipCheckBox.Value;
            app.updateColourmap();
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            value = app.MaxSlider.Value;
            app.MaxEditField.Value = value;
            app.updateColourmap();
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            value = app.MinSlider.Value;
            app.MinEditField.Value = value;
            app.updateColourmap();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlPanelUIFigure = uifigure('Visible', 'off');
            app.ControlPanelUIFigure.Name = 'Control Panel';
            app.ControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ControlPanelUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ControlPanelUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout2 = uigridlayout(app.PositionTab);
            app.ViewOptionButtonGroup = uibuttongroup(app.GridLayout2);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = 'View Option';
            app.XButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.XButton.Text = 'X';
            app.YButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.ZButton.Text = 'Z';
            app.ALLButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.ALLButton.Text = 'ALL';
            app.ALLButton.Value = true;
            app.CrosshairCheckBox = uicheckbox(app.ViewOptionButtonGroup);
            app.CrosshairCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrosshairCheckBoxValueChanged, true);
            app.CrosshairCheckBox.Text = 'Crosshair';
            app.CrosshairCheckBox.Value = true;
            app.XSlider = uislider(app.GridLayout2);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSlider = uislider(app.GridLayout2);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSlider = uislider(app.GridLayout2);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.XWidthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XWidthEditField.ValueChangedFcn = createCallbackFcn(app, @XWidthEditFieldValueChanged, true);
            app.XLabel_2 = uilabel(app.GridLayout3);
            app.XLabel_2.Text = 'ΔX';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.YWidthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YWidthEditField.ValueChangedFcn = createCallbackFcn(app, @YWidthEditFieldValueChanged, true);
            app.YWidthEditField.Value = 0.05;
            app.YLabel_2 = uilabel(app.GridLayout4);
            app.YLabel_2.Text = 'ΔY';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.ZWidthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ZWidthEditField.ValueChangedFcn = createCallbackFcn(app, @ZWidthEditFieldValueChanged, true);
            app.ZWidthEditField.Value = 0.05;
            app.ZLabel_2 = uilabel(app.GridLayout5);
            app.ZLabel_2.Text = 'ΔZ';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.XEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XLabel = uilabel(app.GridLayout6);
            app.XLabel.Text = 'X';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.YEditField = uieditfield(app.GridLayout7, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YLabel = uilabel(app.GridLayout7);
            app.YLabel.Text = 'Y';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.ZEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZLabel = uilabel(app.GridLayout8);
            app.ZLabel.Text = 'Z';
            app.ColourTab = uitab(app.TabGroup);
            app.ColourTab.Title = 'Colour';
            app.GridLayout9 = uigridlayout(app.ColourTab);
            app.MinSlider = uislider(app.GridLayout9);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MaxSlider = uislider(app.GridLayout9);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 100;
            app.GammaSlider = uislider(app.GridLayout9);
            app.GammaSlider.Visible = 'off';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.MinEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MinLabel = uilabel(app.GridLayout10);
            app.MinLabel.Text = 'Min (%)';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.MaxEditField = uieditfield(app.GridLayout11, 'numeric');
            app.MaxEditField.Value = 100;
            app.MaxLabel = uilabel(app.GridLayout11);
            app.MaxLabel.Text = 'Max (%)';
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.GridLayout12.Visible = 'off';
            app.GammaEditField = uieditfield(app.GridLayout12, 'numeric');
            app.GammaLabel = uilabel(app.GridLayout12);
            app.GammaLabel.Text = 'Gamma';
            app.GridLayout13 = uigridlayout(app.GridLayout9);
            app.ColorMapDropDown = uidropdown(app.GridLayout13);
            app.ColorMapDropDown.Items = {'gray', 'hot', 'cool', 'jet', 'parula', 'turbo', 'hsv', 'sky'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'turbo';
            app.FlipCheckBox = uicheckbox(app.GridLayout13);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipCheckBoxValueChanged, true);
            app.FlipCheckBox.Text = 'Flip';
            app.ColourMapLabel = uilabel(app.GridLayout13);
            app.ColourMapLabel.Text = 'Colour Map';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            % Show the figure after all components are created
            app.ControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPanelUIFigure)
        end
    end
end