classdef OxArpes_DataViewer_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARPES3DDataViewerUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        PositionTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        EqualAxisCheckBox           matlab.ui.control.CheckBox
        CrosshairCheckBox           matlab.ui.control.CheckBox
        ZEditField_2                matlab.ui.control.NumericEditField
        ZEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.NumericEditField
        XEditField_2Label           matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        ViewDirectionDropDown       matlab.ui.control.DropDown
        ViewDirectionDropDownLabel  matlab.ui.control.Label
        ColourTab                   matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        ColourMapDropDown           matlab.ui.control.DropDown
        ColourMapDropDownLabel      matlab.ui.control.Label
        ColourRangeSlider           matlab.ui.control.RangeSlider
        ColourRangeSliderLabel      matlab.ui.control.Label
        FlipCheckBox                matlab.ui.control.CheckBox
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DATA_IN % Description
        XX
        YY
        ZZ
        DATA_XY
        DATA_XZ
        DATA_YZ
        DATA_X_
        DATA_Y_
        DATA_Z_

        FigXY
        FigXZ
        FigYZ
        FigXY_ch
        FigXZ_ch
        FigYZ_ch

        FigX_
        FigY_
        FigZ_
    end
    
    methods (Access = private)
        
        function slice_data(app)

            % YZ
            cond1 = ( abs(app.DATA_IN.x - app.XX) <= app.XEditField_2);
            if ~any(cond1)
                [~,cond1] = min(abs(app.DATA_IN.x - app.XX));
            end
            app.DATA_YZ = squeeze(mean(app.DATA_IN.value(cond1,:,:),1))';

            % XZ
            cond2 = ( abs(app.DATA_IN.y - app.YY) <= app.YEditField_2);
            if ~any(cond2)
                [~,cond2] = min(abs(app.DATA_IN.y - app.YY));
            end
            app.DATA_XZ = squeeze(mean(app.DATA_IN.value(:,cond2,:),2))';

            % XY
            cond3 = ( abs(app.DATA_IN.z - app.ZZ) <= app.ZEditField_2 );
            if ~any(cond3)
                [~,cond3] = min(abs(app.DATA_IN.z - app.ZZ));
            end
            app.DATA_XY = squeeze(mean(app.DATA_IN.value(:,:,cond3),3))';

            % X_
            app.DATA_X_ = squeeze(mean(app.DATA_IN.value(:,cond2,cond3),[2 3]));

            % Y_
            app.DATA_Y_ = squeeze(mean(app.DATA_IN.value(cond1,:,cond3),[1 3]));

            % Z_
            app.DATA_Z_ = squeeze(mean(app.DATA_IN.value(cond1,cond2,:),[1 2]));

        end
        
        function display_data(app)
            app.FigXY = imagesc(app.UIAxes,app.DATA_IN.x,app.DATA_IN.y,app.DATA_XY);
            set(app.UIAxes,'YDir','normal');
            app.FigYZ = imagesc(app.UIAxes2,app.DATA_IN.z,app.DATA_IN.y,app.DATA_YZ');
            set(app.UIAxes2,'YDir','normal');
            set(app.UIAxes2,'XDir','reverse');
            app.FigXZ = imagesc(app.UIAxes3,app.DATA_IN.x,app.DATA_IN.z,app.DATA_XZ);
            set(app.UIAxes3,'YDir','normal');

            app.FigX_ = plot(app.UIAxes5,app.DATA_IN.x,app.DATA_X_);
            app.FigY_ = plot(app.UIAxes4,app.DATA_Y_,app.DATA_IN.y);
            app.FigZ_ = plot(app.UIAxes6,app.DATA_Z_,app.DATA_IN.z);

        end
        
        function update_positions(app,x,y,z)
            app.XX = x;
            app.YY = y;
            app.ZZ = z;
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = z;

            app.slice_data();
            app.update_images();


            app.FigXY_ch.Position = [x y];
            app.FigXZ_ch.Position = [x z];
            app.FigYZ_ch.Position = [z y];
            
        end
        
        function update_images(app)
            app.FigXY.CData = app.DATA_XY;
            app.FigXZ.CData = app.DATA_XZ;
            app.FigYZ.CData = app.DATA_YZ';

            app.FigX_.YData = app.DATA_X_;
            app.FigY_.XData = app.DATA_Y_;
            app.FigZ_.XData = app.DATA_Z_;

        end
        
        function update_colourmap(app)

            cm = app.ColourMapDropDown.Value;
            fp = app.FlipCheckBox.Value;
            if fp
                cmf = flip(eval(cm));
                colormap(app.UIAxes,cmf);
                colormap(app.UIAxes2,cmf);
                colormap(app.UIAxes3,cmf);
            else
                colormap(app.UIAxes,cm);
                colormap(app.UIAxes2,cm);
                colormap(app.UIAxes3,cm);
            end
            
            % ll = max(app.MAP.value,[],'all') * app.MinEditField.Value /100;
            % mm = max(app.MAP.value,[],'all') * app.MaxEditField.Value /100;
            % 
            % clim(app.FigX_axe,[ll mm]);
            % clim(app.FigY_axe,[ll mm]);
            % clim(app.FigZ_axe,[ll mm]);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DATA_INPUT)
            app.DATA_IN = DATA_INPUT;

            % initialise
            app.XX = mean(app.DATA_IN.x);
            app.YY = mean(app.DATA_IN.y);
            app.ZZ = mean(app.DATA_IN.z);
            app.XEditField.Value = app.XX;
            app.YEditField.Value = app.YY;
            app.ZEditField.Value = app.ZZ;

            % slice data
            app.slice_data();

            % display data
            app.display_data();

            % set crosshair
            app.FigXY_ch = drawcrosshair('Parent',app.UIAxes,'Position',[app.XX app.YY],'LineWidth',0.5,'Color','c');
            app.FigXZ_ch = drawcrosshair('Parent',app.UIAxes3,'Position',[app.XX app.ZZ],'LineWidth',0.5,'Color','c');
            app.FigYZ_ch = drawcrosshair('Parent',app.UIAxes2,'Position',[app.ZZ app.YY],'LineWidth',0.5,'Color','c');
            addlistener(app.FigXY_ch,'MovingROI',@(src,data) app.update_positions(data.CurrentPosition(1),data.CurrentPosition(2),app.ZZ));
            addlistener(app.FigXZ_ch,'MovingROI',@(src,data) app.update_positions(data.CurrentPosition(1),app.YY,data.CurrentPosition(2)));
            addlistener(app.FigYZ_ch,'MovingROI',@(src,data) app.update_positions(app.XX,data.CurrentPosition(2),data.CurrentPosition(1)));


        end

        % Value changed function: ColourMapDropDown, FlipCheckBox
        function ColourMapDropDownValueChanged(app, event)
            value = app.ColourMapDropDown.Value;
            app.update_colourmap();
        end

        % Value changed function: CrosshairCheckBox
        function CrosshairCheckBoxValueChanged(app, event)
            value = app.CrosshairCheckBox.Value;
            if value == 1
                app.FigXY_ch.Visible = 'on';
                app.FigXZ_ch.Visible = 'on';
                app.FigYZ_ch.Visible = 'on';
            else
                app.FigXY_ch.Visible = 'off';
                app.FigXZ_ch.Visible = 'off';
                app.FigYZ_ch.Visible = 'off'; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARPES3DDataViewerUIFigure = uifigure('Visible', 'off');
            app.ARPES3DDataViewerUIFigure.Name = 'ARPES 3D Data Viewer';
            app.GridLayout = uigridlayout(app.ARPES3DDataViewerUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            colormap(app.UIAxes, 'sky')
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            app.UIAxes2.ZLimitMethod = 'tight';
            colormap(app.UIAxes2, 'sky')
            app.UIAxes3 = uiaxes(app.GridLayout);
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes3.ZLimitMethod = 'tight';
            colormap(app.UIAxes3, 'sky')
            app.UIAxes4 = uiaxes(app.GridLayout);
            app.UIAxes4.YLimitMethod = 'tight';
            app.UIAxes6 = uiaxes(app.GridLayout);
            app.UIAxes6.YLimitMethod = 'tight';
            app.UIAxes5 = uiaxes(app.GridLayout);
            app.UIAxes5.XLimitMethod = 'tight';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout2 = uigridlayout(app.PositionTab);
            app.ViewDirectionDropDownLabel = uilabel(app.GridLayout2);
            app.ViewDirectionDropDownLabel.Text = 'View Direction';
            app.ViewDirectionDropDown = uidropdown(app.GridLayout2);
            app.ViewDirectionDropDown.Items = {'X-Y', 'X-Z', 'Y-Z'};
            app.ViewDirectionDropDown.Value = 'X-Y';
            app.XEditFieldLabel = uilabel(app.GridLayout2);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.4f';
            app.YEditFieldLabel = uilabel(app.GridLayout2);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.4f';
            app.ZEditFieldLabel = uilabel(app.GridLayout2);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.4f';
            app.XEditField_2Label = uilabel(app.GridLayout2);
            app.XEditField_2Label.Text = 'ΔX';
            app.XEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.XEditField_2.ValueDisplayFormat = '%.4f';
            app.YEditField_2Label = uilabel(app.GridLayout2);
            app.YEditField_2Label.Text = 'ΔY';
            app.YEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.YEditField_2.ValueDisplayFormat = '%.4f';
            app.YEditField_2.Value = 0.05;
            app.ZEditField_2Label = uilabel(app.GridLayout2);
            app.ZEditField_2Label.Text = 'ΔZ';
            app.ZEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditField_2.ValueDisplayFormat = '%.4f';
            app.ZEditField_2.Value = 0.05;
            app.CrosshairCheckBox = uicheckbox(app.GridLayout2);
            app.CrosshairCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrosshairCheckBoxValueChanged, true);
            app.CrosshairCheckBox.Text = 'Crosshair';
            app.CrosshairCheckBox.Value = true;
            app.EqualAxisCheckBox = uicheckbox(app.GridLayout2);
            app.EqualAxisCheckBox.Text = 'Equal Axis';
            app.ColourTab = uitab(app.TabGroup);
            app.ColourTab.Title = 'Colour';
            app.GridLayout3 = uigridlayout(app.ColourTab);
            app.FlipCheckBox = uicheckbox(app.GridLayout3);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.FlipCheckBox.Text = 'Flip';
            app.ColourRangeSliderLabel = uilabel(app.GridLayout3);
            app.ColourRangeSliderLabel.Text = 'Colour Range';
            app.ColourRangeSlider = uislider(app.GridLayout3, 'range');
            app.ColourMapDropDownLabel = uilabel(app.GridLayout3);
            app.ColourMapDropDownLabel.Text = 'Colour Map';
            app.ColourMapDropDown = uidropdown(app.GridLayout3);
            app.ColourMapDropDown.Items = {'gray', 'hot', 'cool', 'jet', 'parula', 'turbo', 'hsv', 'sky'};
            app.ColourMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.ColourMapDropDown.Value = 'sky';
            % Show the figure after all components are created
            app.ARPES3DDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataViewer_3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARPES3DDataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARPES3DDataViewerUIFigure)
        end
    end
end