classdef OxArpes_FineStructure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        YStepEditField       matlab.ui.control.NumericEditField
        YStepEditFieldLabel  matlab.ui.control.Label
        XStepEditField       matlab.ui.control.NumericEditField
        XStepEditFieldLabel  matlab.ui.control.Label
        yPixelSpinner        matlab.ui.control.Spinner
        yPixelSpinnerLabel   matlab.ui.control.Label
        xPixelSpinner        matlab.ui.control.Spinner
        xPixelSpinnerLabel   matlab.ui.control.Label
        XSlider              matlab.ui.control.Slider
        XSliderLabel         matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        PlotButton           matlab.ui.control.Button
        RefreshButton        matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = showInAxis(app,ha1,data)
            % imagesc meshc
            cla(ha1);
            imagesc(ha1,data.x,data.y,data.value');
            xlabel(ha1,[data.x_name ' (' data.x_unit ')']);
            ylabel(ha1,[data.y_name ' (' data.y_unit ')']);

            set(ha1,'YDir','normal');
            colormap(ha1,flipud(gray));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Callback function: PlotButton, xPixelSpinner, yPixelSpinner
        function PlotButtonPushed(app, event)
            dataname = app.ListBox.Value;
            
            data = evalin("base",dataname);
            
            if isa(data, 'OxArpes_2D_Data')
                app.showInAxis(app.UIAxes,data);

                data_smooth = data.Gaussian_smoothen(app.xPixelSpinner.Value, app.yPixelSpinner.Value);
                app.showInAxis(app.UIAxes2,data_smooth);

                [D2x_CUT,D2y_CUT] = data.second_derivative(app.xPixelSpinner.Value, app.yPixelSpinner.Value);
                app.showInAxis(app.UIAxes3,D2x_CUT);
                app.showInAxis(app.UIAxes4,D2y_CUT);

            else

            end

        end

        % Value changed function: XStepEditField
        function XStepEditFieldValueChanged(app, event)
            value = app.XStepEditField.Value;
            app.xPixelSpinner.Step = value;
        end

        % Value changed function: YStepEditField
        function YStepEditFieldValueChanged(app, event)
            value = app.YStepEditField.Value;
            app.yPixelSpinner.Step = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'd2/dy2')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLimitMethod = 'tight';
            app.UIAxes4.YLimitMethod = 'tight';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'd2/dx2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Smooth')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout2);
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.XSliderLabel = uilabel(app.GridLayout3);
            app.XSliderLabel.Text = 'X Slider';
            app.XSlider = uislider(app.GridLayout3);
            app.xPixelSpinnerLabel = uilabel(app.GridLayout3);
            app.xPixelSpinnerLabel.Interpreter = 'tex';
            app.xPixelSpinnerLabel.Text = 'σ_x (Pixel)';
            app.xPixelSpinner = uispinner(app.GridLayout3);
            app.xPixelSpinner.Step = 0.5;
            app.xPixelSpinner.Limits = [0 Inf];
            app.xPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.xPixelSpinner.Value = 1;
            app.yPixelSpinnerLabel = uilabel(app.GridLayout3);
            app.yPixelSpinnerLabel.Interpreter = 'tex';
            app.yPixelSpinnerLabel.Text = 'σ_y (Pixel)';
            app.yPixelSpinner = uispinner(app.GridLayout3);
            app.yPixelSpinner.Step = 0.5;
            app.yPixelSpinner.Limits = [0 Inf];
            app.yPixelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.yPixelSpinner.Value = 1;
            app.XStepEditFieldLabel = uilabel(app.GridLayout3);
            app.XStepEditFieldLabel.Text = 'X Step';
            app.XStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XStepEditField.Limits = [0 Inf];
            app.XStepEditField.ValueChangedFcn = createCallbackFcn(app, @XStepEditFieldValueChanged, true);
            app.XStepEditField.Value = 0.5;
            app.YStepEditFieldLabel = uilabel(app.GridLayout3);
            app.YStepEditFieldLabel.Text = 'Y Step';
            app.YStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YStepEditField.Limits = [0 Inf];
            app.YStepEditField.ValueChangedFcn = createCallbackFcn(app, @YStepEditFieldValueChanged, true);
            app.YStepEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_FineStructure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end