classdef OxArpes_Normalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        SaveButton               matlab.ui.control.Button
        SmoothlevelSpinner       matlab.ui.control.Spinner
        SmoothlevelSpinnerLabel  matlab.ui.control.Label
        RenormaliseButton        matlab.ui.control.Button
        FitCurveButton           matlab.ui.control.Button
        YmaxEditField            matlab.ui.control.NumericEditField
        YmaxEditFieldLabel       matlab.ui.control.Label
        YminEditField            matlab.ui.control.NumericEditField
        YminEditFieldLabel       matlab.ui.control.Label
        XmaxEditField            matlab.ui.control.NumericEditField
        XmaxEditFieldLabel       matlab.ui.control.Label
        XminEditField            matlab.ui.control.NumericEditField
        XminEditFieldLabel       matlab.ui.control.Label
        SelectUpdateAreaButton   matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        FlipXYButton             matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data_slice % Description
        data_slice_tk
        data_slice_MDC
        data_slice_MDC_smooth
        data_slice_new

        ROI_MDC
    end
    
    methods (Access = private)
        
        function showInAxis(app,ha1,data)
            % imagesc meshc
            cla(ha1);
            imagesc(ha1,data.x,data.y,data.value');

            xlabel(ha1,[data.x_name ' (' data.x_unit ')']);
            ylabel(ha1,[data.y_name ' (' data.y_unit ')']);

            set(ha1,'YDir','normal');

            colormap(ha1,flipud(gray));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            dataname = app.ListBox.Value;
            app.data_slice = evalin('base',dataname);
            app.showInAxis(app.UIAxes,app.data_slice);
            app.ROI_MDC = [];
        end

        % Button pushed function: SelectUpdateAreaButton
        function SelectUpdateAreaButtonPushed(app, event)
            if isempty(app.ROI_MDC)
                app.ROI_MDC = drawrectangle(app.UIAxes);
            end

            x1 = app.ROI_MDC.Position(1);
            x2 = app.ROI_MDC.Position(1)+app.ROI_MDC.Position(3);
            y1 = app.ROI_MDC.Position(2);
            y2 = app.ROI_MDC.Position(2)+app.ROI_MDC.Position(4);
            
%             rect = getrect(app.UIAxes);
%             x1 = rect(1);
%             x2 = rect(1)+rect(3);
%             y1 = rect(2);
%             y2 = rect(2)+rect(4);


            app.XminEditField.Value = x1;
            app.XmaxEditField.Value = x2;
            app.YminEditField.Value = y1;
            app.YmaxEditField.Value = y2;
            
            app.data_slice_tk = app.data_slice.truncate(x1,x2,y1,y2);
            app.data_slice_MDC = sum(app.data_slice_tk.value,2);

            plot(app.UIAxes2,app.data_slice_tk.x,app.data_slice_MDC);
            xlabel(app.UIAxes2,[app.data_slice.x_name ' (' app.data_slice.x_unit ')']);
            ylabel(app.UIAxes2,'Counts (a.u.)');


        end

        % Button pushed function: FitCurveButton
        function FitCurveButtonPushed(app, event)
            cla(app.UIAxes2);
            app.data_slice_MDC_smooth = movmean(app.data_slice_MDC,app.SmoothlevelSpinner.Value);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.data_slice_tk.x,app.data_slice_MDC);
            xlabel(app.UIAxes2,[app.data_slice.x_name ' (' app.data_slice.x_unit ')']);
            ylabel(app.UIAxes2,'Counts (a.u.)');
            plot(app.UIAxes2,app.data_slice_tk.x,app.data_slice_MDC_smooth);
            hold(app.UIAxes2,'off');

        end

        % Button pushed function: RenormaliseButton
        function RenormaliseButtonPushed(app, event)
            new_MDC_ratio = interp1(app.data_slice_tk.x,app.data_slice_MDC./app.data_slice_MDC_smooth,app.data_slice.x,"cubic",1);
            app.data_slice_new = app.data_slice;
            app.data_slice_new.value = app.data_slice_new.value./repmat(new_MDC_ratio(:),1,length(app.data_slice_new.y));

            app.showInAxis(app.UIAxes3,app.data_slice_new);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin("base",[app.ListBox.Value '_norm'],app.data_slice_new);
        end

        % Button pushed function: FlipXYButton
        function FlipXYButtonPushed(app, event)
            new_data = app.data_slice;
            new_data.value = new_data.value';
            new_data.x = app.data_slice.y;
            new_data.y = app.data_slice.x;

            try
                new_data.x_unit = app.data_slice.y_unit;
                new_data.y_unit = app.data_slice.x_unit;
                new_data.x_label = app.data_slice.y_label;
                new_data.y_label = app.data_slice.x_label;
            catch
            end

            app.data_slice = new_data;

            app.showInAxis(app.UIAxes,app.data_slice);
            app.ROI_MDC = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Normalised')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'MDC')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout2);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FlipXYButton = uibutton(app.GridLayout2, 'push');
            app.FlipXYButton.ButtonPushedFcn = createCallbackFcn(app, @FlipXYButtonPushed, true);
            app.FlipXYButton.Text = 'Flip X/Y';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SelectUpdateAreaButton = uibutton(app.GridLayout3, 'push');
            app.SelectUpdateAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUpdateAreaButtonPushed, true);
            app.SelectUpdateAreaButton.Text = 'Select / Update Area';
            app.XminEditFieldLabel = uilabel(app.GridLayout3);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.GridLayout3);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YminEditFieldLabel = uilabel(app.GridLayout3);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.GridLayout3);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FitCurveButton = uibutton(app.GridLayout3, 'push');
            app.FitCurveButton.ButtonPushedFcn = createCallbackFcn(app, @FitCurveButtonPushed, true);
            app.FitCurveButton.Text = 'Fit Curve';
            app.RenormaliseButton = uibutton(app.GridLayout3, 'push');
            app.RenormaliseButton.ButtonPushedFcn = createCallbackFcn(app, @RenormaliseButtonPushed, true);
            app.RenormaliseButton.Text = 'Renormalise';
            app.SmoothlevelSpinnerLabel = uilabel(app.GridLayout3);
            app.SmoothlevelSpinnerLabel.Tooltip = {'In Pixels'};
            app.SmoothlevelSpinnerLabel.Text = 'Smooth level';
            app.SmoothlevelSpinner = uispinner(app.GridLayout3);
            app.SmoothlevelSpinner.Limits = [1 Inf];
            app.SmoothlevelSpinner.Value = 50;
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_Normalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end