classdef OxArpes_Sync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoSyncLoadUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Lamp                          matlab.ui.control.Lamp
        everysecondsEditField         matlab.ui.control.NumericEditField
        everysecondsEditFieldLabel    matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        CopyLoadButton                matlab.ui.control.Button
        AutoLoadSwitch                matlab.ui.control.Switch
        AutoLoadSwitchLabel           matlab.ui.control.Label
        AutoSyncSwitch                matlab.ui.control.Switch
        AutoSyncSwitchLabel           matlab.ui.control.Label
        DestinationFdrEditField       matlab.ui.control.EditField
        DestinationFdrEditFieldLabel  matlab.ui.control.Label
        SourceFolderEditField         matlab.ui.control.EditField
        SourceFolderEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        t % timer
    end
    
    methods (Access = private)
        
        function timer_callback(app)
            app.CopyLoadButtonPushed();
            % disp('ss');
            stop(app.t);
            if strcmp(app.AutoSyncSwitch.Value,'On')
                start(app.t);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = timer;
            app.t.TimerFcn = @(~,~)app.timer_callback;
        end

        % Button pushed function: CopyLoadButton
        function CopyLoadButtonPushed(app, event)
            app.Lamp.Color = 'g';
            drawnow();

            source_folder = app.SourceFolderEditField.Value;
            destination_folder = app.DestinationFdrEditField.Value;

            % Check if source and destination folders exist
            if ~isfolder(source_folder)
                error('Source folder does not exist.');
            end
            if ~isfolder(destination_folder)
                error('Destination folder does not exist.');
            end
        
            % Get a list of all files in the source and destination folders
            source_files = dir(fullfile(source_folder, '*'));
            dest_files = dir(fullfile(destination_folder, '*'));
        
            % Remove directories from the file lists
            source_files = source_files(~[source_files.isdir]);
            dest_files = dest_files(~[dest_files.isdir]);
        
            % Get the names of the files in both folders
            source_file_names = {source_files.name};
            dest_file_names = {dest_files.name};
        
            % Find files in source folder that are not in destination folder
            files_to_copy = setdiff(source_file_names, dest_file_names);
        
            % Add files with different sizes to the list of files to copy
            for i = 1:length(dest_file_names)
                dest_file_path = fullfile(destination_folder, dest_file_names{i});
                source_file_path = fullfile(source_folder, dest_file_names{i});
                if ismember(dest_file_names{i}, source_file_names)
                    dest_file_info = dir(dest_file_path);
                    source_file_info = dir(source_file_path);
                    if dest_file_info.bytes ~= source_file_info.bytes
                        files_to_copy{end+1} = dest_file_names{i}; %#ok<AGROW>
                    end
                end
            end
        
            % Copy each unique file from source to destination
            for i = 1:length(files_to_copy)
                source_file_path = fullfile(source_folder, files_to_copy{i});
                dest_file_path = fullfile(destination_folder, files_to_copy{i});
                copyfile(source_file_path, dest_file_path);

                app.TextArea.Value = append(app.TextArea.Value,' ',files_to_copy{i});
            end

            scroll(app.TextArea, 'bottom');

            if strcmp(app.AutoLoadSwitch.Value,'On')
                files_to_load = {};
                for i = 1:length(files_to_copy)
                    files_to_load{i} = fullfile(destination_folder, files_to_copy{i});
                end
                loader_UI(files_to_load);
            end
            
            if strcmp(app.AutoSyncSwitch.Value, 'On')
                app.Lamp.Color = 'y';
            else
                app.Lamp.Color = [0.90,0.90,0.90];
            end
            drawnow();
        
            % if isempty(app.TextArea.Value{1})
            %     app.TextArea.Value = [sprintf('Copied %s', files_to_copy{i})];
            % else
            %     app.TextArea.Value = [app.TextArea.Value; sprintf('Copied %s', files_to_copy{i})];
            % end
            % app.TextArea.Value = [app.TextArea.Value; sprintf('>>> %d files copied.', length(files_to_copy))];
        end

        % Value changed function: AutoSyncSwitch
        function AutoSyncSwitchValueChanged(app, event)
            value = app.AutoSyncSwitch.Value;

            if strcmp(value,'On')

                app.CopyLoadButtonPushed();

                app.t.StartDelay = app.everysecondsEditField.Value;
                start(app.t);
            else
                app.Lamp.Color = [0.90,0.90,0.90];
                drawnow();

                stop(app.t)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutoSyncLoadUIFigure = uifigure('Visible', 'off');
            app.AutoSyncLoadUIFigure.Name = 'Auto Sync & Load';
            app.GridLayout = uigridlayout(app.AutoSyncLoadUIFigure);
            app.SourceFolderEditFieldLabel = uilabel(app.GridLayout);
            app.SourceFolderEditFieldLabel.Text = 'Source Folder';
            app.SourceFolderEditField = uieditfield(app.GridLayout, 'text');
            app.DestinationFdrEditFieldLabel = uilabel(app.GridLayout);
            app.DestinationFdrEditFieldLabel.Text = 'Destination Fdr.';
            app.DestinationFdrEditField = uieditfield(app.GridLayout, 'text');
            app.AutoSyncSwitchLabel = uilabel(app.GridLayout);
            app.AutoSyncSwitchLabel.Text = 'Auto Sync';
            app.AutoSyncSwitch = uiswitch(app.GridLayout, 'slider');
            app.AutoSyncSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoSyncSwitchValueChanged, true);
            app.AutoLoadSwitchLabel = uilabel(app.GridLayout);
            app.AutoLoadSwitchLabel.Text = 'Auto Load';
            app.AutoLoadSwitch = uiswitch(app.GridLayout, 'slider');
            app.CopyLoadButton = uibutton(app.GridLayout, 'push');
            app.CopyLoadButton.ButtonPushedFcn = createCallbackFcn(app, @CopyLoadButtonPushed, true);
            app.CopyLoadButton.Text = 'Copy & Load';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'0'};
            app.everysecondsEditFieldLabel = uilabel(app.GridLayout);
            app.everysecondsEditFieldLabel.Text = 'every ? seconds';
            app.everysecondsEditField = uieditfield(app.GridLayout, 'numeric');
            app.everysecondsEditField.Value = 60;
            app.Lamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.AutoSyncLoadUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_Sync

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoSyncLoadUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoSyncLoadUIFigure)
        end
    end
end