classdef OxArpes_Visualise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlotButton             matlab.ui.control.Button
        ndDerivativeButton     matlab.ui.control.Button
        SmoothButton           matlab.ui.control.Button
        sigma_yEditField       matlab.ui.control.NumericEditField
        sigma_yEditFieldLabel  matlab.ui.control.Label
        sigma_xEditField       matlab.ui.control.NumericEditField
        sigmaLabel             matlab.ui.control.Label
        RefreshButton          matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            dataname = app.ListBox.Value;
            PlotSlices(dataname,'x');
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            data2 = data1.Gaussian_smoothen(app.sigma_xEditField.Value,app.sigma_yEditField.Value);
            assignin('base',[dataname '_sm'],data2);
        end

        % Button pushed function: ndDerivativeButton
        function ndDerivativeButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data1 = evalin('base',dataname);
            [data2_x, data2_y] = data1.second_derivative(app.sigma_xEditField.Value,app.sigma_yEditField.Value);
            assignin('base',[dataname '_d2x'],data2_x);
            assignin('base',[dataname '_d2y'],data2_y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.sigmaLabel = uilabel(app.UIFigure);
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = '$\sigma_x$';
            app.sigma_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma_xEditField.Value = 3;
            app.sigma_yEditFieldLabel = uilabel(app.UIFigure);
            app.sigma_yEditFieldLabel.Interpreter = 'latex';
            app.sigma_yEditFieldLabel.Text = '$\sigma_y$';
            app.sigma_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma_yEditField.Value = 3;
            app.SmoothButton = uibutton(app.UIFigure, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.ndDerivativeButton = uibutton(app.UIFigure, 'push');
            app.ndDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @ndDerivativeButtonPushed, true);
            app.ndDerivativeButton.Text = '2nd Derivative';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_Visualise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end